classdef App_BuildTF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildTF_GUIUIFigure          matlab.ui.Figure
        FromHDF5EditFieldLabel       matlab.ui.control.Label
        FromFilePath                 matlab.ui.control.EditField
        ButtonChooseFromFile         matlab.ui.control.Button
        ToHDF5EditFieldLabel         matlab.ui.control.Label
        ToFilePath                   matlab.ui.control.EditField
        ButtonChooseToFile           matlab.ui.control.Button
        CheckToSameAsFromFile        matlab.ui.control.CheckBox
        PathFromEditFieldLabel       matlab.ui.control.Label
        FromDataPath                 matlab.ui.control.EditField
        PathToEditFieldLabel         matlab.ui.control.Label
        ToDataPath                   matlab.ui.control.EditField
        ButtonDisplayData            matlab.ui.control.Button
        ButtonPlotOutFigures         matlab.ui.control.Button
        PlotRunSpinnerLabel          matlab.ui.control.Label
        PlotRunSpinner               matlab.ui.control.Spinner
        TabGroup                     matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        FromPlot                     matlab.ui.control.UIAxes
        ToPlot                       matlab.ui.control.UIAxes
        TFTab                        matlab.ui.container.Tab
        TFPlot                       matlab.ui.control.UIAxes
        ComputeButton                matlab.ui.control.Button
        CheckShowLegend              matlab.ui.control.CheckBox
        CheckShowTreatedData         matlab.ui.control.CheckBox
        ShowoffsomenumbersButton     matlab.ui.control.Button
        SettingsPanel                matlab.ui.container.Panel
        DataTreatmentPanel           matlab.ui.container.Panel
        SGolayPointsToSpinnerLabel   matlab.ui.control.Label
        SGolayPointsToSpinner        matlab.ui.control.Spinner
        MedianFilterToSpinnerLabel   matlab.ui.control.Label
        MedianFilterToSpinner        matlab.ui.control.Spinner
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        InterpolationMethodDropDown  matlab.ui.control.DropDown
        SmoothingdTmsEditFieldLabel  matlab.ui.control.Label
        SmoothingdTmsEditField       matlab.ui.control.NumericEditField
        IsStepCheckBox               matlab.ui.control.CheckBox
        TFComputationPanel           matlab.ui.container.Panel
        RangefromsecSpinnerLabel     matlab.ui.control.Label
        RangefromsecSpinner          matlab.ui.control.Spinner
        tosecSpinnerLabel            matlab.ui.control.Label
        tosecSpinner                 matlab.ui.control.Spinner
        DurationsecSpinnerLabel      matlab.ui.control.Label
        DurationsecSpinner           matlab.ui.control.Spinner
        RunsSpinnerLabel             matlab.ui.control.Label
        RunsSpinner                  matlab.ui.control.Spinner
        AlgorithmDropDownLabel       matlab.ui.control.Label
        AlgorithmDropDown            matlab.ui.control.DropDown
        FunctionDropDownLabel        matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        SetStartingParametersButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        data = struct(); % Description
        smthgComputed = false; % Description
    end
    
    methods (Access = private)
        
        function results = loadSingledataet(app, filePath, dataPath)
            info = h5info(filePath, dataPath);
            results{1} = info.Name;
            results{2} = getTimeFromPath(filePath, dataPath);
            results{3} = h5read(filePath, dataPath);
        end
        
        function results = loadFolderdataet(app, filePath, folderPath)
            
        end
        
        function results = updatePlots(app)
            idxRun = app.PlotRunSpinner.Value;
            
            % Data Plots
            cla(app.FromPlot); cla(app.ToPlot);
            hold(app.FromPlot, 'on'); hold(app.ToPlot, 'on');
            
            % For each timecourse in From = each From Paths
            if app.smthgComputed
                for i=1:size(app.data.lastComput(1).fromDataRaw, 3)
                    nameFrom = app.getSetName(app.data.lastComput(1).fileFrom, app.data.lastComput(1).pathFrom{i});
                    nameTo = app.getSetName(app.data.lastComput(1).fileTo, app.data.lastComput(1).pathTo{i});
                    
                    if app.CheckShowTreatedData.Value
                        tmpFrom = app.data.lastComput(1).fromDataTreated(:, :, i);
                        tmpTo = app.data.lastComput(1).toDataTreated(:, :, i);
                    else
                        tmpFrom = app.data.lastComput(1).fromDataRaw(:, :, i);
                        tmpTo = app.data.lastComput(1).toDataRaw(:, :, i);
                    end
                    plot(app.FromPlot, tmpFrom(:, 1), tmpFrom(:, 2), 'DisplayName', nameFrom);
                    plot(app.ToPlot, tmpTo(:, 1), tmpTo(:, 2), 'DisplayName', nameTo);
                    
                    % Convolving the From Data with the TF corresponding to the selected run (the last one by default)
                    pred = conv(app.data.lastComput(1).fromDataTreated(:, 2, i), app.data.lastComput(i).tf);
                    plot(app.ToPlot, tmpFrom(:, 1), pred(1:length(tmpFrom(:, 1))), 'DisplayName', strcat('Pred. ', nameTo));
                end
            else
                % TODO Adapt this part to multiple path possibility
                nameFrom = app.getSetName(app.FromFilePath.Value, app.FromDataPath.Value);
                nameTo = app.getSetName(app.ToFilePath.Value, app.ToDataPath.Value);
                
                plot(app.FromPlot, app.data.From{2}, app.data.From{3}, 'DisplayName', nameFrom);
                plot(app.ToPlot, app.data.To{2}, app.data.To{3}, 'DisplayName', nameTo);
            end
            if app.CheckShowLegend.Value
                legend(app.FromPlot); legend(app.ToPlot);
            end
            hold(app.FromPlot, 'off'); hold(app.ToPlot, 'off');
            
            % TF Plot
        end
        
        function name = getSetName(app, file, path)
            info = h5info(file, path);
            name = info.Name;
        end
        
        function options = defineOptions(app)
            options.medianFilterN = app.MedianFilterToSpinner.Value;
            options.sgolayPoints_To = app.SGolayPointsToSpinner.Value;
            
            if app.MedianFilterToSpinner.Value == 0
                options.applyMedianToRBC = false;
            else
                options.applyMedianToRBC = true;
            end
            if app.SGolayPointsToSpinner.Value == 0
                options.applySGolayRBC = false;
            else
                options.applySGolayRBC = true;
            end
            options.interpMethod = app.InterpolationMethodDropDown.Value; %'spline'/'pchip'
            options.ruleOutImag = false;
            options.smoothDT = app.SmoothingdTmsEditField.Value;
            options.range = [app.RangefromsecSpinner.Value app.tosecSpinner.Value];
            options.durationTF = app.DurationsecSpinner.Value;
            
            if strcmp(app.FunctionDropDown.Value, '1-Gamma')
                options.func = 'gamma'; % 'gamma'/'logit'/'toeplitz'/'fourier'
            elseif strcmp(app.FunctionDropDown.Value, '2-Logit')
                options.func = 'logit'; % 'gamma'/'logit'/'toeplitz'/'fourier'
            elseif strcmp(app.FunctionDropDown.Value, 'Topelitz')
                options.func = 'toeplitz'; % 'gamma'/'logit'/'toeplitz'/'fourier'
            else
                options.func = 'fourier'; % 'gamma'/'logit'/'toeplitz'/'fourier'
            end
            
            options.paramsTF = [1.15, 0.36, 1.00E-03, 0.04]; % p = [6, 1, 0, 1] for 1-gamma, p = [2 4 1 2 0.3 0.3]; for 2-logit
            
            if strcmp(options.func, 'gamma')
                options.upBnd = [10, 10, 0.001, 10]; %ones(1, 4) * 7; %ones(1, 4) * 7; only if algo allow it (simmulated annealing for ex) for stpe increase 7 to 10
                options.lwBnd = [0.001, 0.001, 0.001, 0.001]; %zeros(1, 4)+0.001;
                %options.lwBnd(3) = 0.3;
            elseif strcmp(options.func, 'logit')
                options.upBnd = ones(1, 6) * 10;
                options.lwBnd = zeros(1, 6)+0.001; options.lwBnd(5) = 1;   % <-- why??
            end
            
            optionsMinSearch = optimset('Display','off',...     % change iter-> off to display no output
                'FunValCheck','off',...  % check objective values
                'MaxFunEvals', 10000,...  % max number of function evaluations allowed
                'MaxIter', 10000,...      % max number of iteration allowed
                'TolFun',1e-8,...        % termination tolerance on the function value
                'TolX',1e-8,...          % termination tolerance on x
                'UseParallel','always'); % always use parallel computation
            
            optionsAnneal = saoptimset('Display','off',...     % change iter-> off to display no output
                'MaxFunEval', Inf,...  % max number of function evaluations allowed
                'MaxIter', Inf,...      % max number of iteration allowed
                'TolFun',1e-8, ... % termination tolerance on the function value
                'ObjectiveLimit', 0);
            
            optionsNunc = optimset('Display', 'off', ...
                'MaxFunEval', 10000,...
                'MaxIter', 10000,...
                'TolFun',1e-8,...
                'TolX',1e-8,...
                'algorithm', 'quasi-newton', ...
                'UseParallel','always');
            
            optionsCon = optimset('Display', 'off', ...
                'MaxFunEval', 10000,...
                'MaxIter', 10000,...
                'TolFun',1e-8,...
                'TolX',1e-8,...
                'algorithm', 'sqp', ...
                'UseParallel','always');
            
            options.algo = 'simulannealbnd'; %simulannealbnd/fmincon/fminunc/fminsearch/toeplitz/fourier
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: ButtonChooseFromFile
        function ButtonChooseFromFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the From HDF5 File');
            app.FromFilePath.Value = [pathFile fileName];
            
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
        end

        % Button pushed function: ButtonChooseToFile
        function ButtonChooseToFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the To HDF5 File');
            app.ToFilePath.Value = [pathFile fileName];
        end

        % Value changed function: CheckToSameAsFromFile
        function CheckToSameAsFromFileValueChanged(app, event)
            value = app.CheckToSameAsFromFile.Value;
            % If To file is the same as From file
            if value
                app.ToFilePath.Value = app.FromFilePath.Value;
                app.ToFilePath.Editable = false;
                app.ButtonChooseToFile.Enable = false;
            else
                app.ToFilePath.Editable = true;
                app.ButtonChooseToFile.Enable = true;
            end
        end

        % Value changing function: FromFilePath
        function FromFilePathValueChanging(app, event)
            changingValue = event.Value;
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
        end

        % Button pushed function: ButtonDisplayData
        function ButtonDisplayDataPushed(app, event)
            app.data = struct();
            
            fileFrom = app.FromFilePath.Value;
            fileTo = app.ToFilePath.Value;
            
            FromPaths = app.FromDataPath.Value;
            ToPaths = app.ToDataPath.Value;
            
            % 3 different cases : single dataet, full folder, random acq/folders separated by commas
            % Final structure is a correspondance between the acq name and the time & timecourse.
            % Created some functions to do it but will just code the "single acq" functionality for now.
            
            %don't forget to take into account the Nrun spinner.
            
            app.data.From = loadSingledataet(app, fileFrom, FromPaths);
            app.data.To = loadSingledataet(app, fileTo, ToPaths);
            
            app.updatePlots();
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            fileFrom = app.FromFilePath.Value;
            fileTo = app.ToFilePath.Value;
            
            % If there is more than one path to take into account.
            % TODO Don't forget to param all the errors
            tmpPath = strsplit(app.FromDataPath.Value, ';');
            if strcmp(tmpPath(end), '')
                pathFrom = tmpPath(1:end-1);
            else
                pathFrom = tmpPath;
            end
            tmpPath = strsplit(app.ToDataPath.Value, ';');
            if strcmp(tmpPath(end), '')
                pathTo = tmpPath(1:end-1);
            else
                pathTo = tmpPath;
            end
            
            % Add here the other settings of the panel
            % Note that the function takes separate settings depending on if an HDF5 file contains step function but as it is "From" files in any case, I just used these param to send to the function.
            Modality = 'tmp';
            Tech = 'tmp';
            MatFile = '';
            saveOut = false;
            twiceOpt = false;
            Mouse = getMouseName(app.FromFilePath.Value);
            
            stepON = app.IsStepCheckBox.Value;
            dt = app.SmoothingdTmsEditField.Value;
            range = [app.RangefromsecSpinner.Value app.tosecSpinner.Value];
            durationTF = app.DurationsecSpinner.Value;
            Nrun = app.RunsSpinner.Value;
            options = app.defineOptions();
            
            resultTF = buildTF_GUIFunc(Modality, Tech, MatFile, Mouse, fileFrom, pathFrom, fileTo, pathTo, stepON,...
                dt, range, durationTF, twiceOpt, saveOut, Nrun, options)
            
            app.data.lastComput = resultsTF;
            app.PlotRunSpinner.Enable = true;
            app.PlotRunSpinner.Limits = [1 Nrun];
            app.PlotRunSpinner.Value = Nrun;
            app.CheckShowTreatedData.Enable = true;
            app.CheckShowTreatedData.Value = true;
            app.smthgComputed = true;
            app.updatePlots();
        end

        % Value changed function: PlotRunSpinner
        function PlotRunSpinnerValueChanged(app, event)
            value = app.PlotRunSpinner.Value;
            
            % Just update the plots
            app.updatePlots();
        end

        % Value changed function: CheckShowTreatedData
        function CheckShowTreatedDataValueChanged(app, event)
            value = app.CheckShowTreatedData.Value;
            
            % Just update the plots
            app.updatePlots();
        end

        % Value changed function: CheckShowLegend
        function CheckShowLegendValueChanged(app, event)
            value = app.CheckShowLegend.Value;
            
            % Just update the plots
            app.updatePlots();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildTF_GUIUIFigure = uifigure;
            app.BuildTF_GUIUIFigure.Name = 'BuildTF_GUI';
            app.FromHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.FromHDF5EditFieldLabel.Text = 'From HDF5';
            app.FromFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.FromFilePath.ValueChangingFcn = createCallbackFcn(app, @FromFilePathValueChanging, true);
            app.ButtonChooseFromFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseFromFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseFromFilePushed, true);
            app.ButtonChooseFromFile.Text = 'Browse';
            app.ToHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.ToHDF5EditFieldLabel.Text = 'To HDF5';
            app.ToFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ButtonChooseToFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseToFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseToFilePushed, true);
            app.ButtonChooseToFile.Text = 'Browse';
            app.CheckToSameAsFromFile = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckToSameAsFromFile.ValueChangedFcn = createCallbackFcn(app, @CheckToSameAsFromFileValueChanged, true);
            app.CheckToSameAsFromFile.Text = 'Same';
            app.PathFromEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathFromEditFieldLabel.Text = 'Path - From';
            app.FromDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.PathToEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathToEditFieldLabel.Text = 'Path - To';
            app.ToDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ButtonDisplayData = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonDisplayData.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisplayDataPushed, true);
            app.ButtonDisplayData.Text = 'Load & Display Data';
            app.ButtonPlotOutFigures = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonPlotOutFigures.Text = 'Plot Out Figures';
            app.PlotRunSpinnerLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PlotRunSpinnerLabel.Enable = 'off';
            app.PlotRunSpinnerLabel.Text = 'Plot Run';
            app.PlotRunSpinner = uispinner(app.BuildTF_GUIUIFigure);
            app.PlotRunSpinner.Limits = [1 Inf];
            app.PlotRunSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotRunSpinnerValueChanged, true);
            app.PlotRunSpinner.Enable = 'off';
            app.PlotRunSpinner.Value = 1;
            app.TabGroup = uitabgroup(app.BuildTF_GUIUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.FromPlot = uiaxes(app.DataTab);
            title(app.FromPlot, 'Title')
            xlabel(app.FromPlot, 'Time (sec)')
            ylabel(app.FromPlot, 'From data')
            app.ToPlot = uiaxes(app.DataTab);
            title(app.ToPlot, 'Title')
            xlabel(app.ToPlot, 'Time (sec)')
            ylabel(app.ToPlot, 'To data')
            app.TFTab = uitab(app.TabGroup);
            app.TFTab.Title = 'TF';
            app.TFPlot = uiaxes(app.TFTab);
            title(app.TFPlot, 'Title')
            xlabel(app.TFPlot, 'X')
            ylabel(app.TFPlot, 'Y')
            app.ComputeButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute & Pray !';
            app.CheckShowLegend = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckShowLegend.ValueChangedFcn = createCallbackFcn(app, @CheckShowLegendValueChanged, true);
            app.CheckShowLegend.Text = 'Show legend';
            app.CheckShowLegend.Value = true;
            app.CheckShowTreatedData = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckShowTreatedData.ValueChangedFcn = createCallbackFcn(app, @CheckShowTreatedDataValueChanged, true);
            app.CheckShowTreatedData.Enable = 'off';
            app.CheckShowTreatedData.Text = 'Show treated data';
            app.ShowoffsomenumbersButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ShowoffsomenumbersButton.Text = 'Show off some numbers';
            app.SettingsPanel = uipanel(app.BuildTF_GUIUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.DataTreatmentPanel = uipanel(app.SettingsPanel);
            app.DataTreatmentPanel.Title = 'Data Treatment';
            app.SGolayPointsToSpinnerLabel = uilabel(app.DataTreatmentPanel);
            app.SGolayPointsToSpinnerLabel.Text = 'SGolay Points - To';
            app.SGolayPointsToSpinner = uispinner(app.DataTreatmentPanel);
            app.SGolayPointsToSpinner.Step = 2;
            app.SGolayPointsToSpinner.Limits = [1 Inf];
            app.SGolayPointsToSpinner.Value = 299;
            app.MedianFilterToSpinnerLabel = uilabel(app.DataTreatmentPanel);
            app.MedianFilterToSpinnerLabel.Text = 'Median Filter - To';
            app.MedianFilterToSpinner = uispinner(app.DataTreatmentPanel);
            app.MedianFilterToSpinner.Limits = [0 Inf];
            app.InterpolationMethodDropDownLabel = uilabel(app.DataTreatmentPanel);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.InterpolationMethodDropDown = uidropdown(app.DataTreatmentPanel);
            app.InterpolationMethodDropDown.Items = {'spline', 'pchip'};
            app.InterpolationMethodDropDown.Value = 'spline';
            app.SmoothingdTmsEditFieldLabel = uilabel(app.DataTreatmentPanel);
            app.SmoothingdTmsEditFieldLabel.Text = 'Smoothing dT (ms)';
            app.SmoothingdTmsEditField = uieditfield(app.DataTreatmentPanel, 'numeric');
            app.SmoothingdTmsEditField.Limits = [1 Inf];
            app.SmoothingdTmsEditField.Value = 50;
            app.IsStepCheckBox = uicheckbox(app.DataTreatmentPanel);
            app.IsStepCheckBox.Text = 'Is "From" a step function ?';
            app.TFComputationPanel = uipanel(app.SettingsPanel);
            app.TFComputationPanel.Title = 'TF Computation';
            app.RangefromsecSpinnerLabel = uilabel(app.TFComputationPanel);
            app.RangefromsecSpinnerLabel.Text = 'Range from (sec)';
            app.RangefromsecSpinner = uispinner(app.TFComputationPanel);
            app.RangefromsecSpinner.Step = 0.5;
            app.RangefromsecSpinner.Value = 5;
            app.tosecSpinnerLabel = uilabel(app.TFComputationPanel);
            app.tosecSpinnerLabel.Text = '... to (sec)';
            app.tosecSpinner = uispinner(app.TFComputationPanel);
            app.tosecSpinner.Step = 0.5;
            app.tosecSpinner.Value = 27;
            app.DurationsecSpinnerLabel = uilabel(app.TFComputationPanel);
            app.DurationsecSpinnerLabel.Text = 'Duration (sec)';
            app.DurationsecSpinner = uispinner(app.TFComputationPanel);
            app.DurationsecSpinner.Step = 0.5;
            app.DurationsecSpinner.Value = 15;
            app.RunsSpinnerLabel = uilabel(app.TFComputationPanel);
            app.RunsSpinnerLabel.Text = 'Runs';
            app.RunsSpinner = uispinner(app.TFComputationPanel);
            app.RunsSpinner.Limits = [1 Inf];
            app.RunsSpinner.Value = 20;
            app.AlgorithmDropDownLabel = uilabel(app.TFComputationPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.TFComputationPanel);
            app.AlgorithmDropDown.Items = {'Simmulated Annealing', 'FminUnc', 'FMinSearch', 'FMinCon'};
            app.AlgorithmDropDown.Value = 'Simmulated Annealing';
            app.FunctionDropDownLabel = uilabel(app.TFComputationPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.TFComputationPanel);
            app.FunctionDropDown.Items = {'1-Gamma', '2-Logit', 'Toeplitz', 'Fourier'};
            app.FunctionDropDown.Value = '1-Gamma';
            app.SetStartingParametersButton = uibutton(app.TFComputationPanel, 'push');
            app.SetStartingParametersButton.Text = 'Set Starting Parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App_BuildTF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildTF_GUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildTF_GUIUIFigure)
        end
    end
end