classdef App_BuildTF_LITE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildTF_GUIUIFigure          matlab.ui.Figure
        FromHDF5EditFieldLabel       matlab.ui.control.Label
        FromFilePath                 matlab.ui.control.EditField
        ButtonChooseFromFile         matlab.ui.control.Button
        ToHDF5EditFieldLabel         matlab.ui.control.Label
        ToFilePath                   matlab.ui.control.EditField
        ButtonChooseToFile           matlab.ui.control.Button
        CheckToSameAsFromFile        matlab.ui.control.CheckBox
        PathFromEditFieldLabel       matlab.ui.control.Label
        FromDataPath                 matlab.ui.control.EditField
        PathToEditFieldLabel         matlab.ui.control.Label
        ToDataPath                   matlab.ui.control.EditField
        ButtonDisplayData            matlab.ui.control.Button
        ButtonPlotOutFigures         matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        FromPlot                     matlab.ui.control.UIAxes
        ToPlot                       matlab.ui.control.UIAxes
        TFTab                        matlab.ui.container.Tab
        TFPlot                       matlab.ui.control.UIAxes
        OptimizedparametersPanel     matlab.ui.container.Panel
        P1EditField_2Label           matlab.ui.control.Label
        OptP1                        matlab.ui.control.NumericEditField
        P2EditField_2Label           matlab.ui.control.Label
        OptP2                        matlab.ui.control.NumericEditField
        P3EditField_2Label           matlab.ui.control.Label
        OptP3                        matlab.ui.control.NumericEditField
        P4EditField_2Label           matlab.ui.control.Label
        OptP4                        matlab.ui.control.NumericEditField
        ResidualvalueEditFieldLabel  matlab.ui.control.Label
        ResidualvalueEditField       matlab.ui.control.NumericEditField
        ComputeButton                matlab.ui.control.Button
        SettingsPanel                matlab.ui.container.Panel
        SmoothingdTmsEditFieldLabel  matlab.ui.control.Label
        deltaT                       matlab.ui.control.NumericEditField
        RangefromsecSpinnerLabel     matlab.ui.control.Label
        rangeDataBegin               matlab.ui.control.Spinner
        tosecSpinnerLabel            matlab.ui.control.Label
        rangeDataEnd                 matlab.ui.control.Spinner
        DurationsecSpinnerLabel      matlab.ui.control.Label
        durationTF                   matlab.ui.control.Spinner
        SetStartingParametersforOptimizationPanel  matlab.ui.container.Panel
        P1EditFieldLabel             matlab.ui.control.Label
        SetP1                        matlab.ui.control.NumericEditField
        P2EditFieldLabel             matlab.ui.control.Label
        SetP2                        matlab.ui.control.NumericEditField
        P3EditFieldLabel             matlab.ui.control.Label
        SetP3                        matlab.ui.control.NumericEditField
        P4EditFieldLabel             matlab.ui.control.Label
        SetP4                        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        data = struct(); % Description
        smthgComputed = false; % Description
    end
    
    methods (Access = private)
        
        function results = loadSingledataet(app, filePath, dataPath)
            info = h5info(filePath, dataPath);
            results{1} = info.Name;
            results{2} = getTimeFromPath(filePath, dataPath);
            results{3} = h5read(filePath, dataPath);
        end
        
        function results = loadFolderdataet(app, filePath, folderPath)
            return
        end
        
        function results = updatePlots(app)
            
            % Data Plots
            cla(app.FromPlot); cla(app.ToPlot); cla(app.TFPlot);
            hold(app.FromPlot, 'on'); hold(app.ToPlot, 'on'); hold(app.TFPlot, 'on');
            
            % For each timecourse in From = each From Paths
            if app.smthgComputed
                for i=1:size(app.data.lastComput(1).fromDataRaw, 3)
                    nameFrom = app.getSetName(app.data.lastComput(1).fileFrom, app.data.lastComput(1).pathFrom{i});
                    nameTo = app.getSetName(app.data.lastComput(1).fileTo, app.data.lastComput(1).pathTo{i});
                    
                    tmpFrom = app.data.lastComput(1).fromDataTreated(:, :, i);
                    tmpTo = app.data.lastComput(1).toDataTreated(:, :, i);

                    plot(app.FromPlot, tmpFrom(:, 1), tmpFrom(:, 2), 'DisplayName', nameFrom);
                    plot(app.ToPlot, tmpTo(:, 1), tmpTo(:, 2), 'DisplayName', nameTo);
                    
                    % Convolving the From Data with the TF corresponding to the selected run (the last one by default)
                    pred = conv(app.data.lastComput(1).fromDataTreated(:, 2, i), app.data.lastComput(i).TF);
                    plot(app.ToPlot, tmpFrom(:, 1), pred(1:length(tmpFrom(:, 1))), 'DisplayName', strcat('Pred. ', nameTo));
                    timeTF = 0:app.deltaT.Value*0.001:app.durationTF.Value;
                    plot(app.TFPlot, timeTF, app.data.lastComput(i).TF, 'DisplayName', 'TF');
                    legend(app.ToPlot);
                    app.OptP1.Value = app.data.lastComput(1).paramTF(1);
                    app.OptP2.Value = app.data.lastComput(1).paramTF(2);
                    app.OptP3.Value = app.data.lastComput(1).paramTF(3);
                    app.OptP4.Value = app.data.lastComput(1).paramTF(4);
                    
                    app.ResidualvalueEditField.Value = app.data.lastComput.finalSSResid;
                end               
            else
                % TODO Adapt this part to multiple path possibility
                nameFrom = app.getSetName(app.FromFilePath.Value, app.FromDataPath.Value);
                nameTo = app.getSetName(app.ToFilePath.Value, app.ToDataPath.Value);
                
                plot(app.FromPlot, app.data.From{2}, app.data.From{3}, 'DisplayName', nameFrom);
                plot(app.ToPlot, app.data.To{2}, app.data.To{3}, 'DisplayName', nameTo);
            end
            
            hold(app.FromPlot, 'off'); hold(app.ToPlot, 'off'); hold(app.TFPlot, 'off');
        end
        
        function name = getSetName(app, file, path)
            info = h5info(file, path);
            name = info.Name;
        end
        
        
    end
    

    methods (Access = private)

        % Button pushed function: ButtonChooseFromFile
        function ButtonChooseFromFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the From HDF5 File');
            app.FromFilePath.Value = [pathFile fileName];
            
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
        end

        % Button pushed function: ButtonChooseToFile
        function ButtonChooseToFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the To HDF5 File');
            app.ToFilePath.Value = [pathFile fileName];
        end

        % Value changed function: CheckToSameAsFromFile
        function CheckToSameAsFromFileValueChanged(app, event)
            value = app.CheckToSameAsFromFile.Value;
            % If To file is the same as From file
            if value
                app.ToFilePath.Value = app.FromFilePath.Value;
                app.ToFilePath.Editable = false;
                app.ButtonChooseToFile.Enable = false;
            else
                app.ToFilePath.Editable = true;
                app.ButtonChooseToFile.Enable = true;
            end
        end

        % Value changing function: FromFilePath
        function FromFilePathValueChanging(app, event)
            changingValue = event.Value;
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
        end

        % Button pushed function: ButtonDisplayData
        function ButtonDisplayDataPushed(app, event)
            app.data = struct();
            
            fileFrom = app.FromFilePath.Value;
            fileTo = app.ToFilePath.Value;
            
            FromPaths = app.FromDataPath.Value;
            ToPaths = app.ToDataPath.Value;
            
            % 3 different cases : single dataet, full folder, random acq/folders separated by commas
            % Final structure is a correspondance between the acq name and the time & timecourse.
            % Created some functions to do it but will just code the "single acq" functionality for now.
            
            %don't forget to take into account the Nrun spinner.
            
            app.data.From = loadSingledataet(app, fileFrom, FromPaths);
            app.data.To = loadSingledataet(app, fileTo, ToPaths);
            
            app.smthgComputed = false;
            app.updatePlots();
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            fileFrom = app.FromFilePath.Value;
            fileTo = app.ToFilePath.Value;
            
            % If there is more than one path to take into account.
            % TODO Don't forget to param all the errors
            tmpPath = strsplit(app.FromDataPath.Value, ';');
            if strcmp(tmpPath(end), '')
                pathFrom = tmpPath(1:end-1);
            else
                pathFrom = tmpPath;
            end
            tmpPath = strsplit(app.ToDataPath.Value, ';');
            if strcmp(tmpPath(end), '')
                pathTo = tmpPath(1:end-1);
            else
                pathTo = tmpPath;
            end
            
            % Add here the other settings of the panel
            % Note that the function takes separate settings depending on if an HDF5 file contains step function but as it is "From" files in any case, I just used these param to send to the function.
            
            dt = app.deltaT.Value * 0.001;
            range = [app.rangeDataBegin.Value app.rangeDataEnd.Value];
            startParams = [app.SetP1.Value app.SetP2.Value app.SetP3.Value app.SetP4.Value];
            
            resultsTF = buildTF_GUIFunc_Lite(fileFrom, pathFrom, fileTo, pathTo, ...
                startParams, dt, range, app.durationTF.Value);
            
            app.data.lastComput = resultsTF;
            app.smthgComputed = true;
            app.updatePlots();
        end

        % Callback function
        function PlotRunSpinnerValueChanged(app, event)
            value = app.PlotRunSpinner.Value;
            
            % Just update the plots
            app.updatePlots();
        end

        % Button pushed function: ButtonPlotOutFigures
        function ButtonPlotOutFiguresPushed(app, event)
            fig = figure; hold on;
            delete(gca);
            figAxes = axes(fig);
            
            allChildren = app.FromPlot.XAxis.Parent.Children;
            copyobj(allChildren, figAxes); legend();
            hold off;
            
            fig = figure; hold on;
            delete(gca);
            figAxes = axes(fig);
            
            allChildren = app.ToPlot.XAxis.Parent.Children;
            copyobj(allChildren, figAxes); legend()
            hold off;
            
            fig = figure; hold on;
            delete(gca);
            figAxes = axes(fig);
            
            allChildren = app.TFPlot.XAxis.Parent.Children;
            copyobj(allChildren, figAxes); legend()
            hold off;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildTF_GUIUIFigure = uifigure;
            app.BuildTF_GUIUIFigure.Name = 'BuildTF_GUI';
            app.FromHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.FromHDF5EditFieldLabel.Text = 'From HDF5';
            app.FromFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.FromFilePath.ValueChangingFcn = createCallbackFcn(app, @FromFilePathValueChanging, true);
            app.ButtonChooseFromFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseFromFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseFromFilePushed, true);
            app.ButtonChooseFromFile.Text = 'Browse';
            app.ToHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.ToHDF5EditFieldLabel.Text = 'To HDF5';
            app.ToFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ButtonChooseToFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseToFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseToFilePushed, true);
            app.ButtonChooseToFile.Text = 'Browse';
            app.CheckToSameAsFromFile = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckToSameAsFromFile.ValueChangedFcn = createCallbackFcn(app, @CheckToSameAsFromFileValueChanged, true);
            app.CheckToSameAsFromFile.Text = 'Same';
            app.PathFromEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathFromEditFieldLabel.Text = 'Path - From';
            app.FromDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.PathToEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathToEditFieldLabel.Text = 'Path - To';
            app.ToDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ButtonDisplayData = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonDisplayData.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisplayDataPushed, true);
            app.ButtonDisplayData.Text = 'Load & Display Data';
            app.ButtonPlotOutFigures = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonPlotOutFigures.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlotOutFiguresPushed, true);
            app.ButtonPlotOutFigures.Text = 'Plot Out Figures';
            app.TabGroup = uitabgroup(app.BuildTF_GUIUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.FromPlot = uiaxes(app.DataTab);
            title(app.FromPlot, 'From')
            xlabel(app.FromPlot, 'Time (s)')
            ylabel(app.FromPlot, 'From data')
            app.ToPlot = uiaxes(app.DataTab);
            title(app.ToPlot, 'To')
            xlabel(app.ToPlot, 'Time (s)')
            ylabel(app.ToPlot, 'To data')
            app.TFTab = uitab(app.TabGroup);
            app.TFTab.Title = 'TF';
            app.TFPlot = uiaxes(app.TFTab);
            title(app.TFPlot, 'TF')
            xlabel(app.TFPlot, 'Time(s)')
            ylabel(app.TFPlot, 'Amplitude')
            app.OptimizedparametersPanel = uipanel(app.TFTab);
            app.OptimizedparametersPanel.Title = 'Optimized parameters';
            app.P1EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P1EditField_2Label.Text = 'P1';
            app.OptP1 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.OptP1.Limits = [0.001 10];
            app.OptP1.ValueDisplayFormat = '%.5f';
            app.OptP1.Editable = 'off';
            app.OptP1.Value = 6;
            app.P2EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P2EditField_2Label.Text = 'P2';
            app.OptP2 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.OptP2.Limits = [0.001 10];
            app.OptP2.ValueDisplayFormat = '%.5f';
            app.OptP2.Editable = 'off';
            app.OptP2.Value = 1;
            app.P3EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P3EditField_2Label.Text = 'P3';
            app.OptP3 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.OptP3.Limits = [0.001 10];
            app.OptP3.ValueDisplayFormat = '%.5f';
            app.OptP3.Editable = 'off';
            app.OptP3.Value = 0.001;
            app.P4EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P4EditField_2Label.Text = 'P4';
            app.OptP4 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.OptP4.Limits = [0.001 10];
            app.OptP4.ValueDisplayFormat = '%.5f';
            app.OptP4.Editable = 'off';
            app.OptP4.Value = 1;
            app.ResidualvalueEditFieldLabel = uilabel(app.TFTab);
            app.ResidualvalueEditFieldLabel.Text = 'Residual value';
            app.ResidualvalueEditField = uieditfield(app.TFTab, 'numeric');
            app.ResidualvalueEditField.ValueDisplayFormat = '%.5f';
            app.ResidualvalueEditField.Editable = 'off';
            app.ComputeButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute TF';
            app.SettingsPanel = uipanel(app.BuildTF_GUIUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.SmoothingdTmsEditFieldLabel = uilabel(app.SettingsPanel);
            app.SmoothingdTmsEditFieldLabel.Text = 'Smoothing dT (ms)';
            app.deltaT = uieditfield(app.SettingsPanel, 'numeric');
            app.deltaT.Limits = [1 Inf];
            app.deltaT.Value = 50;
            app.RangefromsecSpinnerLabel = uilabel(app.SettingsPanel);
            app.RangefromsecSpinnerLabel.Text = 'Range from (sec)';
            app.rangeDataBegin = uispinner(app.SettingsPanel);
            app.rangeDataBegin.Step = 0.5;
            app.rangeDataBegin.Value = 5;
            app.tosecSpinnerLabel = uilabel(app.SettingsPanel);
            app.tosecSpinnerLabel.Text = '... to (sec)';
            app.rangeDataEnd = uispinner(app.SettingsPanel);
            app.rangeDataEnd.Step = 0.5;
            app.rangeDataEnd.Value = 27;
            app.DurationsecSpinnerLabel = uilabel(app.SettingsPanel);
            app.DurationsecSpinnerLabel.Text = 'Duration (sec)';
            app.durationTF = uispinner(app.SettingsPanel);
            app.durationTF.Step = 0.5;
            app.durationTF.Value = 15;
            app.SetStartingParametersforOptimizationPanel = uipanel(app.SettingsPanel);
            app.SetStartingParametersforOptimizationPanel.Title = 'Set Starting Parameters for Optimization';
            app.P1EditFieldLabel = uilabel(app.SetStartingParametersforOptimizationPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.SetP1 = uieditfield(app.SetStartingParametersforOptimizationPanel, 'numeric');
            app.SetP1.Limits = [0.001 10];
            app.SetP1.Value = 6;
            app.P2EditFieldLabel = uilabel(app.SetStartingParametersforOptimizationPanel);
            app.P2EditFieldLabel.Text = 'P2';
            app.SetP2 = uieditfield(app.SetStartingParametersforOptimizationPanel, 'numeric');
            app.SetP2.Limits = [0.001 10];
            app.SetP2.Value = 1;
            app.P3EditFieldLabel = uilabel(app.SetStartingParametersforOptimizationPanel);
            app.P3EditFieldLabel.Text = 'P3';
            app.SetP3 = uieditfield(app.SetStartingParametersforOptimizationPanel, 'numeric');
            app.SetP3.Limits = [0.001 10];
            app.SetP3.Value = 0.001;
            app.P4EditFieldLabel = uilabel(app.SetStartingParametersforOptimizationPanel);
            app.P4EditFieldLabel.Text = 'P4';
            app.SetP4 = uieditfield(app.SetStartingParametersforOptimizationPanel, 'numeric');
            app.SetP4.Limits = [0.001 10];
            app.SetP4.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App_BuildTF_LITE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildTF_GUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildTF_GUIUIFigure)
        end
    end
end