classdef DisplayParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayTFParameters       matlab.ui.Figure
        CloseButton               matlab.ui.control.Button
        equaImgButton             matlab.ui.control.Button
        equaLabel                 matlab.ui.control.Label
        OptimizedparametersPanel  matlab.ui.container.Panel
        P1EditField_2Label        matlab.ui.control.Label
        P1                        matlab.ui.control.NumericEditField
        P2EditField_2Label        matlab.ui.control.Label
        P2                        matlab.ui.control.NumericEditField
        P3EditField_2Label        matlab.ui.control.Label
        P3                        matlab.ui.control.NumericEditField
        P4EditField_2Label        matlab.ui.control.Label
        P4                        matlab.ui.control.NumericEditField
        P5Label                   matlab.ui.control.Label
        P5                        matlab.ui.control.NumericEditField
        P6Label                   matlab.ui.control.Label
        P6                        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        callingApp % Description
        params % Description
    end

    methods (Access = public)
    
        function setDisplay(app, func, params)
             %setting the equation image and label right
            if strcmp(func, 'gamma')
                app.equaLabel.Text = '1-Gamma transfer function';
                app.equaImgButton.Icon = './Equa_Gamma.jpg';
                app.P5.Enable = 'off';
                app.P6.Enable = 'off';
                
                
            elseif strcmp(func, 'logit')
                app.equaLabel.Text = '2-Inverse Logit transfer function';
                app.equaImgButton.Icon = './Equa_Logit.jpg';
                app.P5.Enable = 'on';
                app.P6.Enable = 'on';
            end
            
            
            app.P1.Value = params(1);
            app.P2.Value = params(2);
            app.P3.Value = params(3);
            app.P4.Value = params(4);
            
            if strcmp(func, 'logit')
                app.P5.Value = params(5);
                app.P6.Value = params(6);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, func, params)
            app.callingApp = mainApp;

            setDisplay(app, func, params);
        end

        % Callback function: CloseButton, DisplayTFParameters
        function DisplayTFParametersCloseRequest(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplayTFParameters = uifigure;
            app.DisplayTFParameters.Name = 'Display TF Parameters';
            app.DisplayTFParameters.CloseRequestFcn = createCallbackFcn(app, @DisplayTFParametersCloseRequest, true);
            app.CloseButton = uibutton(app.DisplayTFParameters, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTFParametersCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.equaImgButton = uibutton(app.DisplayTFParameters, 'push');
            app.equaImgButton.Icon = 'Equa_Gamma.jpg';
            app.equaImgButton.Text = '';
            app.equaLabel = uilabel(app.DisplayTFParameters);
            app.equaLabel.Text = '1-Gamma transfer function';
            app.OptimizedparametersPanel = uipanel(app.DisplayTFParameters);
            app.OptimizedparametersPanel.Title = 'Optimized parameters';
            app.P1EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P1EditField_2Label.Text = 'P1';
            app.P1 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P1.ValueDisplayFormat = '%.5f';
            app.P1.Editable = 'off';
            app.P1.Value = 6;
            app.P2EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P2EditField_2Label.Text = 'P2';
            app.P2 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P2.ValueDisplayFormat = '%.5f';
            app.P2.Editable = 'off';
            app.P2.Value = 1;
            app.P3EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P3EditField_2Label.Text = 'P3';
            app.P3 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P3.ValueDisplayFormat = '%.5f';
            app.P3.Editable = 'off';
            app.P3.Value = 0.001;
            app.P4EditField_2Label = uilabel(app.OptimizedparametersPanel);
            app.P4EditField_2Label.Text = 'P4';
            app.P4 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P4.ValueDisplayFormat = '%.5f';
            app.P4.Editable = 'off';
            app.P4.Value = 1;
            app.P5Label = uilabel(app.OptimizedparametersPanel);
            app.P5Label.Text = 'P5';
            app.P5 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P5.ValueDisplayFormat = '%.5f';
            app.P5.Editable = 'off';
            app.P5.Value = 0.001;
            app.P6Label = uilabel(app.OptimizedparametersPanel);
            app.P6Label.Text = 'P6';
            app.P6 = uieditfield(app.OptimizedparametersPanel, 'numeric');
            app.P6.ValueDisplayFormat = '%.5f';
            app.P6.Editable = 'off';
            app.P6.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DisplayParams(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayTFParameters)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayTFParameters)
        end
    end
end