classdef Full < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IliskiUIFigure                matlab.ui.Figure
        DisplayDataButton             matlab.ui.control.Button
        PlotOutFiguresButton          matlab.ui.control.Button
        ComputeButton                 matlab.ui.control.Button
        CheckShowLegend               matlab.ui.control.CheckBox
        CheckShowTreatedData          matlab.ui.control.CheckBox
        PlotRunSpinnerLabel           matlab.ui.control.Label
        PlotRunSpinner                matlab.ui.control.Spinner
        ResidualvalueEditFieldLabel   matlab.ui.control.Label
        ResidualvalueEditField        matlab.ui.control.NumericEditField
        PearsonvalueEditFieldLabel    matlab.ui.control.Label
        PearsonvalueEditField         matlab.ui.control.NumericEditField
        DataTFPanel                   matlab.ui.container.Panel
        FromPlot                      matlab.ui.control.UIAxes
        ToPlot                        matlab.ui.control.UIAxes
        TFPlot                        matlab.ui.control.UIAxes
        AboutButton                   matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PretreatmentTab               matlab.ui.container.Tab
        ToPanel                       matlab.ui.container.Panel
        SGolayFilterptsLabel_2        matlab.ui.control.Label
        SGolayPointsToSpinner         matlab.ui.control.Spinner
        MedianFilterptsLabel_2        matlab.ui.control.Label
        MedianFilterToSpinner         matlab.ui.control.Spinner
        FromBoxcargeneratorPanel      matlab.ui.container.Panel
        UseStepCheckbox               matlab.ui.control.CheckBox
        BaselinesSpinnerLabel         matlab.ui.control.Label
        BaselineStepSpinner           matlab.ui.control.Spinner
        UpsSpinnerLabel               matlab.ui.control.Label
        UpStepSpinner                 matlab.ui.control.Spinner
        TotalsSpinnerLabel            matlab.ui.control.Label
        TotalSpinner                  matlab.ui.control.Spinner
        FromPanel                     matlab.ui.container.Panel
        SGolayFilterptsLabel          matlab.ui.control.Label
        SGolayPointsFromSpinner       matlab.ui.control.Spinner
        MedianFilterptsLabel          matlab.ui.control.Label
        MedianFilterFromSpinner       matlab.ui.control.Spinner
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        InterpolationMethodDropDown   matlab.ui.control.DropDown
        SamplingtimemsLabel           matlab.ui.control.Label
        deltaT                        matlab.ui.control.Spinner
        Settingtheparametersvalueto0cancelthefilteringLabel  matlab.ui.control.Label
        BothPanel                     matlab.ui.container.Panel
        RangefromsLabel               matlab.ui.control.Label
        rangeDataBegin                matlab.ui.control.Spinner
        toLabel                       matlab.ui.control.Label
        rangeDataEnd                  matlab.ui.control.Spinner
        TFComputationTab              matlab.ui.container.Tab
        TFComputationPanel            matlab.ui.container.Panel
        DurationsLabel                matlab.ui.control.Label
        durationTF                    matlab.ui.control.Spinner
        NumberofrunsSpinnerLabel      matlab.ui.control.Label
        NumberofrunsSpinner           matlab.ui.control.Spinner
        FunctionDropDownLabel         matlab.ui.control.Label
        FunctionDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        twiceOpt                      matlab.ui.control.CheckBox
        UseLastComputParamsAsInitialButton  matlab.ui.control.Button
        GoSavingPanelButton           matlab.ui.control.Button
        AuditoryCueCheckBox           matlab.ui.control.CheckBox
        SetParamsTable                matlab.ui.control.Table
        ComputedParamsTable           matlab.ui.control.Table
        EditFieldLabel                matlab.ui.control.Label
        paramFunctionStrEditField     matlab.ui.control.EditField
        SavingLoadingresultsTab       matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        MATfileTab                    matlab.ui.container.Tab
        ResultsFromFileDropDownLabel  matlab.ui.control.Label
        ResultsFromFileDropDown       matlab.ui.control.DropDown
        LoadResultFileButton          matlab.ui.control.Button
        FileEditFieldLabel            matlab.ui.control.Label
        SavingFileEditField           matlab.ui.control.EditField
        ButtonChooseSaveFile          matlab.ui.control.Button
        SavingInfoLabel               matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        XLSfileTab                    matlab.ui.container.Tab
        FileEditFieldLabel_2          matlab.ui.control.Label
        SavingXLSFileEditField        matlab.ui.control.EditField
        ButtonChooseXLSFile           matlab.ui.control.Button
        SaveXLSButton                 matlab.ui.control.Button
        CheckShowInitialTF            matlab.ui.control.CheckBox
        LoadingFilesTab               matlab.ui.container.TabGroup
        TextFilesTab                  matlab.ui.container.Tab
        FromFileEditFieldLabel        matlab.ui.control.Label
        FromTextFilePath              matlab.ui.control.EditField
        ToFileEditFieldLabel          matlab.ui.control.Label
        ToTextFilePath                matlab.ui.control.EditField
        ChooseFromTextFileButton      matlab.ui.control.Button
        ChooseToTextFileButton        matlab.ui.control.Button
        InformationsButton            matlab.ui.control.Button
        HDF5FilesTab                  matlab.ui.container.Tab
        ChooseFromH5FileButton        matlab.ui.control.Button
        ChooseToH5FileButton          matlab.ui.control.Button
        CheckToSameAsFromH5File       matlab.ui.control.CheckBox
        FromHDF5EditFieldLabel        matlab.ui.control.Label
        FromH5FilePath                matlab.ui.control.EditField
        ToHDF5EditFieldLabel          matlab.ui.control.Label
        ToH5FilePath                  matlab.ui.control.EditField
        PathFromEditFieldLabel        matlab.ui.control.Label
        FromH5DataPath                matlab.ui.control.EditField
        PathToEditFieldLabel          matlab.ui.control.Label
        ToH5DataPath                  matlab.ui.control.EditField
        LoadfromButtonGroup           matlab.ui.container.ButtonGroup
        LoadFromTextFilesButton       matlab.ui.control.RadioButton
        LoadFromHDF5FilesButton       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        data = struct(); % Description
        smthgComputed = false; % Description
        smthgLoaded % Description
        PARAM_FUNCTIONS_FILE = 'DefaultParametricFunctions_Iliski.txt'; % Description
        HELP_FILE = 'Help.txt';
        ABOUT_FILE = 'About.txt';
        LOG_FILE = 'Errors.log';
        INFO_TEXT_FILE = 'InfoOnLoadingTextFiles.txt';
        parametricFctHandles = {}; % Description
        NON_PARAM_ALGO = {'fourier', 'toeplitz'};
        computationParamDefaultValues = {}; % Description
        loadedResults = nan; % Description
    end
    
    methods (Access = private)
        
        function results = loadSingleDatasetFromHDF5(app, filePath, dataPath)
            try
                info = h5info(filePath, dataPath);
                results{1} = info.Name;
                results{2} = Iliski_getTimeFromPath(filePath, dataPath);
                results{3} = squeeze(h5read(filePath, dataPath));
                
                if ndims(results{2}) > 2 || (size(results{2}, 1) ~= 1 && size(results{2}, 2) ~= 1) % If there are more than a single dimension
                    errMsg = 'The time vector for your data is not a single dimensional array. It should be a single list of time values. Check its validity.';
                    throw(MException('Iliski:LoadingH5File:TimeVectorInvalid', errMsg));
                elseif ndims(results{2}) == 2 && size(results{2}, 1) ~= 1 && size(results{2}, 2) == 1 % Need to be changed to a single row array
                    results{2} = reshape(results{2}, [1, length(results{2})]);
                end
                
                if ndims(results{3}) > 2 || (size(results{3}, 1) ~= 1 && size(results{3}, 2) ~= 1) % If there are more than a single dimension
                    errMsg = 'The data vector is not a single dimensional array. It should be a single list of values. Check its validity.';
                    throw(MException('Iliski:LoadingH5File:TimeVectorInvalid', errMsg));
                elseif ndims(results{3}) == 2 && size(results{3}, 1) ~= 1 && size(results{3}, 2) == 1 % Need to be changed to a single row array
                    results{3} = reshape(results{3}, [1, length(results{3})]);
                end
            catch ME
                [~, name, ext] = fileparts(filePath);
                if strfind(ME.identifier, 'fileOpenErr')
                    errMsg = ['The file ' name ext ' does not seem to be accessible at the specified path. Check the syntax or close the file before trying again.'];
                    throw(MException('Iliski:LoadingH5File:FileNotAccessible', errMsg));
                elseif strfind(ME.identifier, 'unableToFind')
                    errMsg = ['The specified path inside ' name ext ' is not valid. Please correct it before trying again.'];
                    throw(MException('Iliski:LoadingH5File:DataPathInvalid', errMsg));
                else
                    rethrow(ME);
                end
            end
        end
        
        
        function results = updatePlots(app)
            assignin('base', 'appData', app.data);
            
            run = app.PlotRunSpinner.Value;
            % Data Plots
            cla(app.FromPlot); cla(app.ToPlot); cla(app.TFPlot);
            hold(app.FromPlot, 'on'); hold(app.ToPlot, 'on'); hold(app.TFPlot, 'on');
            
            % For each timecourse in From = each From Paths
            if app.smthgComputed
                if app.CheckShowTreatedData.Value
                    tmpFrom = app.data.lastComput.Computed.FromTreated;
                    tmpTo =  app.data.lastComput.Computed.ToTreated;
                    legendName = 'Filtered form';
                else
                    tmpFrom = app.data.lastComput.InputData.From;
                    tmpTo = app.data.lastComput.InputData.To;
                    legendName = 'Raw form';
                end
                
                plot(app.FromPlot, tmpFrom(:, 1), tmpFrom(:, 2), 'LineWidth', 2, 'Color', [0, 0.4470, 0.7410], 'DisplayName', legendName);
                plot(app.ToPlot, tmpTo(:, 1), tmpTo(:, 2),  'LineWidth', 2, 'Color', [0.9290, 0.6940, 0.1250], 'DisplayName', legendName);
                
                plot(app.ToPlot, app.data.lastComput.Computed.Prediction(:, 1, run), app.data.lastComput.Computed.Prediction(:, 2, run), ...
                    'LineWidth', 2, 'Color', [0.6350, 0.0780, 0.1840], 'DisplayName', 'TF Prediction');
                % timeTF = 0:app.deltaT.Value*0.001:(length(app.data.lastComput{run}.TF)-1)*app.deltaT.Value*0.001;
                
                plot(app.TFPlot, app.data.lastComput.Computed.TF(:, 1, run), app.data.lastComput.Computed.TF(:, 2, run), ...
                    'DisplayName', 'Computed TF', 'LineWidth', 2, 'Color', [0.4940, 0.1840, 0.5560]);
                
                if app.CheckShowInitialTF.Value && ~isempty(app.data.lastComput.Header.InitialTF)
                    plot(app.TFPlot, app.data.lastComput.Header.InitialTF(:, 1), app.data.lastComput.Header.InitialTF(:, 2), ...
                        'DisplayName', 'Initial TF', 'LineWidth', 2, 'Color', 'k');
                end
                
                app.ResidualvalueEditField.Enable = 'on';
                app.ResidualvalueEditFieldLabel.Enable = 'on';
                app.ResidualvalueEditField.Value = app.data.lastComput.Computed.ResidualSumSquare(run);
                app.PearsonvalueEditField.Enable = 'on';
                app.PearsonvalueEditFieldLabel.Enable = 'on';
                app.PearsonvalueEditField.Value = app.data.lastComput.Computed.Pearson(run);
                
                app.updateComputedParamsTab(run);
            else
                legendName = 'Raw form';
                plot(app.FromPlot, app.data.From{2}, app.data.From{3}, 'LineWidth', 2, 'Color', [0, 0.4470, 0.7410], 'DisplayName', legendName);
                plot(app.ToPlot, app.data.To{2}, app.data.To{3}, 'LineWidth', 2, 'Color', [0.9290, 0.6940, 0.1250], 'DisplayName', legendName);
                cla(app.TFPlot);
                app.updateComputedParamsTab();
            end
            
            % Updating the legend visible state
            if app.CheckShowLegend.Value
                legend(app.FromPlot); legend(app.ToPlot);
                
                % Show legend of TF Plot only if there is data plotted inside
                if ~isempty(app.TFPlot.Children)
                    legend(app.TFPlot);
                else
                    legend(app.TFPlot, 'off');
                end
            else
                legend(app.FromPlot, 'off'); legend(app.ToPlot, 'off'); legend(app.TFPlot, 'off');
            end
            
            
            hold(app.FromPlot, 'off'); hold(app.ToPlot, 'off'); hold(app.TFPlot, 'off');
        end
        
        
        function updateEnableLoadDisplayButton(app)
            % This function evaluates if the Load & Display button can be enabled, if there is enough information in the GUI
            
            if app.LoadFromHDF5FilesButton.Value
                if ~isempty(app.ToH5FilePath.Value) && ~isempty(app.ToH5DataPath.Value) && ...
                        (app.UseStepCheckbox.Value || (~isempty(app.FromH5FilePath.Value) && ~isempty(app.FromH5DataPath.Value)))
                    app.DisplayDataButton.Enable = 'on';
                else
                    app.DisplayDataButton.Enable = 'off';
                end
            elseif app.LoadFromTextFilesButton.Value
                if ~isempty(app.FromTextFilePath.Value) && ~isempty(app.ToTextFilePath.Value)
                    app.DisplayDataButton.Enable = 'on';
                else
                    app.DisplayDataButton.Enable = 'off';
                end
            end
        end
        
        function updateParametersTab(app)
            
            idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
            if idxFct <= length(app.parametricFctHandles)
                app.SetParamsTable.Data = app.computationParamDefaultValues{idxFct}.lastParams;
            elseif idxFct <= length(app.parametricFctHandles)+2 % Toeplitz or Fourier
                app.SetParamsTable.Data = [];
            else
                try
                    paramNames = Iliski_extractParametersFromHandle(str2func(app.paramFunctionStrEditField.Value));
                    paramNames = paramNames(1:end-1);
                    nbParams = length(paramNames);
                    paramArray = table(paramNames, repelem(2, nbParams)', repelem(0.001, nbParams)', repelem(10, nbParams)');
                    app.SetParamsTable.Data = paramArray;
                catch ME
                    app.SetParamsTable.Data = [];
                    
                    rethrow(ME);
                end
            end
        end
        
        function [paramsTF, lwBnd, upBnd] = extractParamsTable(app)
            paramsTF = table2array(app.SetParamsTable.Data(:, 2))';
            lwBnd = table2array(app.SetParamsTable.Data(:, 3))';
            upBnd = table2array(app.SetParamsTable.Data(:, 4))';
        end
        
        function updateComputedParamsTab(app, i)
            if app.smthgComputed
                isNonParamFct = strcmp(app.data.lastComput.Header.Algorithm, ...
                    app.NON_PARAM_ALGO);
                if ~isNonParamFct
                    if exist('i')
                        app.ComputedParamsTable.Data = app.data.lastComput.Computed.Parameters(:, i);
                    else
                        app.ComputedParamsTable.Data = app.data.lastComput.Computed.Parameters(:, 1);
                    end
                end
            else
                app.ComputedParamsTable.Data = [];
            end
        end
        
        function valid = checkValiditySavingFile(app, fileName)
            errValidity = Iliski_isSavingFileValid(fileName);
            valid = isempty(errValidity);
            if valid
                if app.smthgComputed
                    app.SaveButton.Enable = 'on';
                end
                app.LoadResultFileButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
                app.LoadResultFileButton.Enable = 'off';
            end
        end
        
        function fillLoadedResults(app)
            try
                if isa(app.loadedResults, 'struct')
                    % Progress bar
                    progressBar = uiprogressdlg(app.IliskiUIFigure, 'Title', 'Loading results', ...
                        'Indeterminate', 'on');
                    
                    app.PlotRunSpinner.Value = 1;
                    app.smthgComputed = true;
                    app.data.lastComput = app.loadedResults.(app.ResultsFromFileDropDown.Value);
                    
                    app.MedianFilterFromSpinner.Value = app.data.lastComput.Header.MedianFilterFrom;
                    app.MedianFilterToSpinner.Value = app.data.lastComput.Header.MedianFilterTo;
                    app.SGolayPointsFromSpinner.Value = app.data.lastComput.Header.SGolayFilterFrom;
                    app.SGolayPointsFromSpinnerValueChanged();
                    app.SGolayPointsToSpinner.Value = app.data.lastComput.Header.SGolayFilterTo;
                    app.SGolayPointsToSpinnerValueChanged();
                    app.InterpolationMethodDropDown.Value = app.data.lastComput.Header.InterpolationMethod;
                    app.deltaT.Value = app.data.lastComput.Header.SamplingTime * 1000;
                    app.rangeDataBegin.Value = app.data.lastComput.Header.TimeIntervalRawData(1);
                    app.rangeDataEnd.Value = app.data.lastComput.Header.TimeIntervalRawData(2);
                    
                    app.durationTF.Value = app.data.lastComput.Header.DurationTF;
                    
                    if app.data.lastComput.Header.Iterations > 1
                        app.PlotRunSpinner.Enable = 'on';
                        app.PlotRunSpinner.Limits = [1, app.data.lastComput.Header.Iterations];
                    else
                        app.PlotRunSpinner.Enable = 'off';
                    end
                    app.NumberofrunsSpinner.Value = app.data.lastComput.Header.Iterations;
                    
                    app.CheckShowInitialTF.Enable = 'on';
                    
                    app.FunctionDropDown.Value = 'Personalized';
                    app.paramFunctionStrEditField.Value = func2str(app.data.lastComput.Header.Function);
                    app.updateParametersTab();
                    app.SetParamsTable.Data(:, 2) = table(app.data.lastComput.Header.InitialParameters');
                    app.SetParamsTable.Data(:, 3) = table(app.data.lastComput.Header.LowerBoundParameters');
                    app.SetParamsTable.Data(:, 4) = table(app.data.lastComput.Header.UpperBoundParameters');
                    
                    if strcmp(app.data.lastComput.Header.Algorithm, 'simulannealbnd')
                        app.AlgorithmDropDown.Value = 'Simulated Annealing';
                    elseif strcmp(app.data.lastComput.Header.Algorithm, 'fminunc')
                        app.AlgorithmDropDown.Value = 'FMinUnc';
                    elseif strcmp(app.data.lastComput.Header.Algorithm, 'fminsearch')
                        app.AlgorithmDropDown.Value = 'FMinSearch';
                    elseif strcmp(app.data.lastComput.Header.Algorithm, 'fmincon')
                        app.AlgorithmDropDown.Value = 'FMinCon';
                    end
                    
                    app.twiceOpt.Value = app.data.lastComput.Header.FMinUncAfterSimulAnl;
                    
                    if ~isempty(strfind(app.data.lastComput.InputData.fileTo, '.h5'))
                        app.FromH5DataPath.Value = app.data.lastComput.InputData.pathFrom;
                        app.ToH5DataPath.Value = app.data.lastComput.InputData.pathTo;
                        app.FromH5FilePath.Value = app.data.lastComput.InputData.fileFrom;
                        app.ToH5FilePath.Value = app.data.lastComput.InputData.fileTo;
                        
                        app.FromTextFilePath.Value = ''; app.ToTextFilePath.Value = '';
                        app.LoadFromHDF5FilesButton.Value = true;
                    else
                        app.FromH5DataPath.Value = '';
                        app.ToH5DataPath.Value = '';
                        app.FromH5FilePath.Value = '';
                        app.ToH5FilePath.Value = '';
                        
                        app.FromTextFilePath.Value = app.data.lastComput.InputData.fileFrom;
                        app.ToTextFilePath.Value = app.data.lastComput.InputData.fileTo;
                        app.LoadFromTextFilesButton.Value = true;
                    end
                    
                    app.data.From = {}; app.data.To = {};
                    app.data.From{1} = ''; app.data.From{2} = app.data.lastComput.InputData.From(:, 1)'; app.data.From{3} = app.data.lastComput.InputData.From(:, 2)';
                    app.data.To{1} = ''; app.data.To{2} = app.data.lastComput.InputData.To(:, 1)'; app.data.To{3} = app.data.lastComput.InputData.To(:, 2)';
                    app.smthgLoaded = true;
                    app.ComputeButton.Enable = 'on';
                    
                    app.updatePlots();
                    close(progressBar);
                end
            catch ME
                close(progressBar);
                rethrow(ME);
            end
        end
        
        function results = loadSingleDatasetFromText(app, filePath)
            try
                [path, name, ext] = fileparts(filePath);
                rawTxt = dlmread(filePath);
                
                results{1} = name;
                if size(rawTxt, 2) == 2
                    results{2} = reshape(rawTxt(:, 1), [1, length(rawTxt(:, 1))]);
                    results{3} = reshape(rawTxt(:, 2), [1, length(rawTxt(:, 2))]);
                elseif size(rawTxt, 2) == 1
                    results{2} = 0:1:length(rawTxt) - 1;
                    results{3} = reshape(rawTxt, [1, length(rawTxt)]);
                else % size(rawTxt, 2) > 2 More than 2 columns
                    results{2} = reshape(rawTxt(:, 1), [1, length(rawTxt(:, 1))]);
                    results{3} = reshape(rawTxt(:, 2), [1, length(rawTxt(:, 2))]);
                    warnMsg = ['The file ' name ext ' appears to have more than 2 columns inside. Only the first two, as time vector and data vector, have been taken into account.'];
                    warndlg(warnMsg, 'Input data file too wide');
                end
            catch ME
                if strfind(ME.identifier, 'FileNotOpened')
                    errMsg = ['The file ' name ext ' does not seem to be accessible at the specified path. Check the syntax or close the file before trying again.'];
                    throw(MException('Iliski:LoadingTextFile:FilePathInvalid', errMsg));
                elseif strfind(ME.identifier, 'handleErrorAndShowInfo')
                    errMsg = ['The file ' name ext ' does not seem to be readable. Check the format of the data inside by refering to the user manual (see Help).'];
                    throw(MException('Iliski:LoadingTextFile:FileFormatInvalid', errMsg));
                else
                    rethrow(ME);
                end
            end
        end
        
        function catchErrorAndInform(app, MEx, IliskiTitle)
            try
                title = '';
                msg = '';
                log = ['\n----------------------------------------\n' datestr(now) '\n'];
                if strfind(MEx.identifier, 'Iliski')
                    log = [log 'ILISKI ERROR:' MEx.identifier];
                    title = IliskiTitle;
                    msg = MEx.message;
                    log = [log '\n\n' MEx.message '\n'];
                else
                    log = [log 'MATLAB ERROR:' MEx.identifier];
                    title = 'MATLAB Error';
                    msg = 'Iliski encountered an unknown MATLAB error.';
                    log = [log '\n\n' MEx.message '\n\n' getReport(MEx, 'extended', 'hyperlinks', 'off') '\n'];
                end
                errMsg = {msg; ''; 'Contact the developer if you have trouble fixing it (see Help).'};
                
                id = fopen(app.LOG_FILE, 'a');
                fprintf(id, log);
                fclose(id);
                
                errordlg(errMsg, title, 'error');
            catch ME
                msg = 'Iliski encountered an error while trying to treat another error. Please contact the developer (see Help) to fix the situation.';
                errordlg(msg, 'Iliski Error', 'error');
            end
        end
        
        function checkValiditySavingXLSFile(app, fileName)
            errValidity = Iliski_isSavingXLSFileValid(fileName);
            valid = isempty(errValidity);
            if valid
                if app.smthgComputed
                    app.SaveXLSButton.Enable = 'on';
                end
            else
                app.SaveXLSButton.Enable = 'off';
            end
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Reading and loading the parametric functions from the corresponding file.
                [paramFctNames, paramFctHandles, defParams] = Iliski_readFunctionsFile(app.PARAM_FUNCTIONS_FILE);
                app.parametricFctHandles = paramFctHandles;
                
                app.FunctionDropDown.Items = {paramFctNames{:}, 'Toeplitz', 'Fourier', 'Personalized'};
                app.FunctionDropDown.Value = paramFctNames{1};
                app.paramFunctionStrEditField.Value = func2str(paramFctHandles{1});
                
                for i=1:length(paramFctHandles)
                    app.computationParamDefaultValues{i} = struct();
                    app.computationParamDefaultValues{i}.fctHandle = func2str(paramFctHandles{i});
                    
                    
                    paramNames = Iliski_extractParametersFromHandle(paramFctHandles{i});
                    paramNames = paramNames(1:end-1);
                    nbParams = length(paramNames);
                    if length(defParams{i}) == nbParams
                        paramArray = table(paramNames, defParams{i}', defParams{i}'/100, defParams{i}'*10);
                    else
                        err = ['Error while reading the default parameters for the function handle, in line ', ...
                            num2str(i), ' of ', app.PARAM_FUNCTIONS_FILE, '. Number of given default parameters (', ...
                            num2str(length(defParams{i})) ') do not correspond to the actual number of parameters ', ...
                            'of this function (' num2str(nbParams) ').'];
                        throw(MException('Iliski:ParametricFunctions:ParsingHandleError', err));
                    end
                    
                    app.computationParamDefaultValues{i}.lastParams = paramArray;
                    
                end
                
                app.updateParametersTab();
            catch ME
                app.catchErrorAndInform(ME, 'Starting Up Error');
                return
            end
        end

        % Button pushed function: ChooseFromH5FileButton
        function ChooseFromH5FileButtonPushed(app, event)
            try
                [fileName, pathFile] = uigetfile('.h5', 'Select the From HDF5 File');
            app.FromH5FilePath.Value = [pathFile fileName];
            
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromH5File.Value
                app.ToH5FilePath.Value = app.FromH5FilePath.Value;
            end
            
            if app.UseStepCheckbox.Value
                app.UseStepCheckbox.Value = 0;
                app.UseStepCheckboxValueChanged();
            end
            
            app.LoadFromHDF5FilesButton.Value = 1;
            
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Button pushed function: ChooseToH5FileButton
        function ChooseToH5FileButtonPushed(app, event)
            try
                [fileName, pathFile] = uigetfile('.h5', 'Select the To HDF5 File');
            app.ToH5FilePath.Value = [pathFile fileName];
            app.LoadFromHDF5FilesButton.Value = 1;
            
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Value changed function: CheckToSameAsFromH5File
        function CheckToSameAsFromH5FileValueChanged(app, event)
            try
                value = app.CheckToSameAsFromH5File.Value;
            % If To file is the same as From file
            if value
                app.ToH5FilePath.Value = app.FromH5FilePath.Value;
                app.ToH5FilePath.Editable = false;
                app.ChooseToH5FileButton.Enable = false;
            else
                app.ToH5FilePath.Editable = true;
                app.ChooseToH5FileButton.Enable = true;
            end
            app.LoadFromHDF5FilesButton.Value = 1;
            
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Callback function: BaselineStepSpinner, DisplayDataButton, 
        % UpStepSpinner
        function DisplayDataButtonPushed(app, event)
            app.data = struct();
            try
                if app.LoadFromHDF5FilesButton.Value
                    fileFrom = app.FromH5FilePath.Value;
                    fileTo = app.ToH5FilePath.Value;
                    
                    FromPaths = app.FromH5DataPath.Value;
                    ToPaths = app.ToH5DataPath.Value;
                elseif app.LoadFromTextFilesButton.Value
                    fileFrom = app.FromTextFilePath.Value;
                    fileTo = app.ToTextFilePath.Value;
                end
                
                if app.UseStepCheckbox.Value
                    [timeVector, boxcarFct] = Iliski_generateBoxcarFunction(app.deltaT.Value/1000, app.BaselineStepSpinner.Value, ...
                        app.UpStepSpinner.Value, app.TotalSpinner.Value);
                    boxcarFrom = {};
                    boxcarFrom{1} = 'Boxcar function';
                    boxcarFrom{2} = timeVector;
                    boxcarFrom{3} = boxcarFct;
                    app.data.From = boxcarFrom;
                elseif app.LoadFromHDF5FilesButton.Value
                    app.data.From = loadSingleDatasetFromHDF5(app, fileFrom, FromPaths);
                elseif app.LoadFromTextFilesButton.Value
                    app.data.From = loadSingleDatasetFromText(app, fileFrom);
                end
                
                if app.LoadFromHDF5FilesButton.Value
                    app.data.To = loadSingleDatasetFromHDF5(app, fileTo, ToPaths);
                elseif app.LoadFromTextFilesButton.Value
                    app.data.To = loadSingleDatasetFromText(app, fileTo);
                end
                
                app.smthgComputed = false;
                
                app.ComputeButton.Enable = 'on';
                app.SaveButton.Enable = 'off';
                app.SaveXLSButton.Enable = 'off';
                app.CheckShowTreatedData.Enable = 'off'; app.CheckShowTreatedData.Value = false;
                app.CheckShowInitialTF.Enable = 'off'; app.CheckShowInitialTF.Value = false;
                
                app.ResidualvalueEditField.Enable = 'off';
                app.ResidualvalueEditFieldLabel.Enable = 'off';
                app.ResidualvalueEditField.Value = 0;
                app.PearsonvalueEditField.Enable = 'off';
                app.PearsonvalueEditFieldLabel.Enable = 'off';
                app.PearsonvalueEditField.Value = 0;
                
                app.PlotRunSpinner.Enable = 'off';
                app.PlotRunSpinnerLabel.Enable = 'off';
                app.PlotRunSpinner.Value = 1;
                
                app.UseLastComputParamsAsInitialButton.Enable = 'off';
                app.GoSavingPanelButton.Enable = 'off';
                
                app.smthgLoaded = true;
                
                app.updatePlots();
            catch ME
                app.catchErrorAndInform(ME, 'Data loading error');
                return
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            try
                app.DisplayDataButtonPushed();
                
                % Emptying the 'loaded' old results file, as it can cause bugs
                app.loadedResults = NaN;
                app.ResultsFromFileDropDown.Items = {};
                
                options.Iterations= app.NumberofrunsSpinner.Value;
                options.MedianFilterTo = app.MedianFilterToSpinner.Value;
                options.SGolayFilterTo = app.SGolayPointsToSpinner.Value;
                options.MedianFilterFrom = app.MedianFilterFromSpinner.Value;
                options.SGolayFilterFrom = app.SGolayPointsFromSpinner.Value;
                options.InterpolationMethod = app.InterpolationMethodDropDown.Value; %'spline'/'pchip'/'linear'
                options.SamplingTime = app.deltaT.Value * 0.001;
                options.TimeIntervalRawData = [app.rangeDataBegin.Value app.rangeDataEnd.Value];
                options.DurationTF = app.durationTF.Value;
                options.IsFromStep = app.UseStepCheckbox.Value;
                options.FMinUncAfterSimulAnl = app.twiceOpt.Value;
                
                if strcmp(app.AlgorithmDropDown.Value, 'Simulated Annealing')
                    options.Algorithm = 'simulannealbnd';
                elseif strcmp(app.AlgorithmDropDown.Value, 'FMinUnc')
                    options.Algorithm = 'fminunc';
                elseif strcmp(app.AlgorithmDropDown.Value, 'FMinSearch')
                    options.Algorithm = 'fminsearch';
                elseif strcmp(app.AlgorithmDropDown.Value, 'FMinCon')
                    options.Algorithm = 'fmincon';
                end
                
                if app.UseStepCheckbox.Value
                    options.MedianFilterFrom = 0;
                    options.SGolayFilterFrom = 0;
                    options.InterpolationMethod = 'None';
                end
                
                
                if strcmp(app.FunctionDropDown.Value, 'Toeplitz')
                    options.Function = 'toeplitz';
                    options.Algorithm = 'toeplitz';
                elseif strcmp(app.FunctionDropDown.Value, 'Fourier')
                    options.Function = 'fourier';
                    options.Algorithm = 'fourier';
                else
                    if strcmp(app.FunctionDropDown.Value, 'Personalized')
                        options.Function = str2func(app.paramFunctionStrEditField.Value);
                    else
                        idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
                        options.Function = app.parametricFctHandles{idxFct};
                    end
                    [paramsTF, lwBnd, upBnd] = app.extractParamsTable();
                    options.InitialParameters = paramsTF;
                    options.LowerBoundParameters = lwBnd;
                    options.UpperBoundParameters = upBnd;
                end
                
                dataFrom = [app.data.From{2}' app.data.From{3}'];
                dataTo = [app.data.To{2}' app.data.To{3}'];
                
                % Progress bar
                progressBar = uiprogressdlg(app.IliskiUIFigure, 'Title', 'Computing TF', ...
                    'Indeterminate', 'on');
                try
                    resultsTF = Iliski_TF(dataFrom, dataTo, options);
                catch ME
                    close(progressBar);
                    rethrow(ME);
                end
                close(progressBar);
                
                % Adding side Info that is not in the resultsTF struct
                if app.UseStepCheckbox.Value
                    resultsTF.InputData.fileFrom = 'Boxcar function';
                    resultsTF.InputData.pathFrom = 'Boxcar function';
                elseif app.LoadFromHDF5FilesButton.Value
                    resultsTF.InputData.fileFrom = app.FromH5FilePath.Value;
                    resultsTF.InputData.pathFrom = app.FromH5DataPath.Value;
                elseif app.LoadFromTextFilesButton.Value
                    resultsTF.InputData.fileFrom = app.FromTextFilePath.Value;
                end
                
                if app.LoadFromHDF5FilesButton.Value
                    resultsTF.InputData.fileTo = app.ToH5FilePath.Value;
                    resultsTF.InputData.pathTo = app.ToH5DataPath.Value;
                elseif app.LoadFromTextFilesButton.Value
                    resultsTF.InputData.fileTo = app.ToTextFilePath.Value;
                end
                
                
                %%
                
                
                app.smthgComputed = true;
                app.CheckShowTreatedData.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.SaveXLSButton.Enable = 'on';
                app.UseLastComputParamsAsInitialButton.Enable = 'on';
                app.GoSavingPanelButton.Enable = 'on';
                
                % Updating the "Last Params" array to save the used parameters for an ulterior use in this same session
                idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
                if idxFct <= length(app.parametricFctHandles)
                    app.computationParamDefaultValues{idxFct}.lastParams = app.SetParamsTable.Data;
                end
                
                if (strcmp(app.data.lastComput.Header.Algorithm, 'simulannealbnd') ...
                        || app.data.lastComput.Header.FMinUncAfterSimulAnl) && app.NumberofrunsSpinner.Value > 1
                    app.PlotRunSpinner.Enable = 'on';
                    app.PlotRunSpinnerLabel.Enable = 'on';
                    app.PlotRunSpinner.Limits = [1 app.data.lastComput.Header.Iterations];
                    app.PlotRunSpinner.Value = 1;
                else
                    app.PlotRunSpinner.Enable = 'off';
                    app.PlotRunSpinner.Value = 1;
                end
                
                % If parametric function
                if ~(strcmp(app.FunctionDropDown.Value, 'Fourier') || strcmp(app.FunctionDropDown.Value, 'Toeplitz'))
                    app.CheckShowInitialTF.Enable = 'on';
                else
                    app.CheckShowInitialTF.Enable = 'off';
                    app.CheckShowInitialTF.Value = false;
                end
                
                % Auditory cue
                if app.AuditoryCueCheckBox.Value
                    load gong; sound(y, Fs);
                end
                
                app.updatePlots();
            catch ME
                app.catchErrorAndInform(ME, 'Computation Error');
                return
            end
        end

        % Value changed function: PlotRunSpinner
        function PlotRunSpinnerValueChanged(app, event)
            try
                value = app.PlotRunSpinner.Value;
            
            % Just update the plots
            app.updatePlots();
            catch ME
                app.catchErrorAndInform(ME, 'Plotting Error');
                return
            end
        end

        % Button pushed function: PlotOutFiguresButton
        function PlotOutFiguresButtonPushed(app, event)
            try
                allChildren = app.FromPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'From', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend();
                hold off;
            end
            
            allChildren = app.ToPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'To', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend()
                hold off;
            end
            
            allChildren = app.TFPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'TF', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend()
                hold off;
            end
            catch ME
                app.catchErrorAndInform(ME, 'Plotting Error');
                return
            end
        end

        % Value changed function: CheckShowTreatedData
        function CheckShowTreatedDataValueChanged(app, event)
            try
                value = app.CheckShowTreatedData.Value;
            if app.smthgLoaded
                app.updatePlots();
            end
            catch ME
                app.catchErrorAndInform(ME, 'Plotting Error');
                return
            end
        end

        % Value changed function: CheckShowLegend
        function CheckShowLegendValueChanged(app, event)
            try
                value = app.CheckShowLegend.Value;
            app.updatePlots();
            catch ME
                app.catchErrorAndInform(ME, 'Plotting Error');
                return
            end
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            try
                value = app.FunctionDropDown.Value;
            
            % If non-parametric functions
            if strcmp(value, 'Fourier') || strcmp(value, 'Toeplitz')
                app.AlgorithmDropDown.Enable = 'off';
                app.twiceOpt.Enable = 'off';
                app.twiceOpt.Value = false;
                app.paramFunctionStrEditField.Editable = 'off';
                app.paramFunctionStrEditField.Value = 'DECONVOLUTIONAL METHOD - NON-PARAMETRIC FUNCTION';
                app.UseLastComputParamsAsInitialButton.Enable = 'off';
                app.NumberofrunsSpinner.Value = 1; app.NumberofrunsSpinner.Enable = 'off';
                app.CheckShowInitialTF.Enable = 'off'; app.CheckShowInitialTF.Value = false;
                app.updateParametersTab();
            else
                app.AlgorithmDropDown.Enable = 'on';
                app.twiceOpt.Enable = 'on';
                app.NumberofrunsSpinner.Enable = 'on';
                app.CheckShowInitialTF.Enable = 'on';
                
                if strcmp(value, 'Personalized')
                    app.paramFunctionStrEditField.Editable = 'on';
                    app.paramFunctionStrEditField.Value = '@(parameters) function';
                else
                    app.paramFunctionStrEditField.Editable = 'off';
                    idxFct = find(strcmp(app.FunctionDropDown.Items, value));
                    app.paramFunctionStrEditField.Value = func2str(app.parametricFctHandles{idxFct});
                    
                    if app.smthgComputed && isa(app.data.lastComput.Header.Function, 'function_handle') && ...
                            strcmp(app.paramFunctionStrEditField.Value, func2str(app.data.lastComput.Header.Function))
                        app.UseLastComputParamsAsInitialButton.Enable = 'on';
                    else
                        app.UseLastComputParamsAsInitialButton.Enable = 'off';
                    end
                    
                    
                    app.updateParametersTab();
                end
            end
            app.AlgorithmDropDownValueChanged();
            catch ME
                app.catchErrorAndInform(ME, 'TF function Error');
                return
            end
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            try
                value = app.AlgorithmDropDown.Value;
            
            if strcmp(value, 'Simulated Annealing')
                app.NumberofrunsSpinner.Enable = 'on';
            else
                app.NumberofrunsSpinner.Enable = 'off';
                app.NumberofrunsSpinner.Value = 1;
            end
            
            % If parametric function
            if ~(strcmp(app.FunctionDropDown.Value, 'Fourier') || strcmp(app.FunctionDropDown.Value, 'Toeplitz'))
                app.CheckShowInitialTF.Enable = 'on';
            else
                app.CheckShowInitialTF.Enable = 'off';
                app.CheckShowInitialTF.Value = false;
            end
            catch ME
                app.catchErrorAndInform(ME, 'TF algorithm Error');
                return
            end
        end

        % Close request function: IliskiUIFigure
        function IliskiUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: twiceOpt
        function twiceOptValueChanged(app, event)
            value = app.twiceOpt.Value;
            if value
                app.AlgorithmDropDown.Value = 'Simulated Annealing';
                app.AlgorithmDropDownValueChanged();
                app.AlgorithmDropDown.Enable = 'off';
            else
                app.AlgorithmDropDown.Enable = 'on';
            end
            
        end

        % Button pushed function: ButtonChooseSaveFile
        function ChooseSaveFileButtonPushed(app, event)
            try
                [fileName, pathFile] = uiputfile('.mat', 'Select an (in)existent file to save your results');
            if fileName ~= 0
                app.SavingFileEditField.Value = [pathFile fileName];
                app.checkValiditySavingFile(app.SavingFileEditField.Value);
            end
            catch ME
                app.catchErrorAndInform(ME, 'Saving file Error');
                return
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Checking if all parameter fields are filled
            try
                errValidity = Iliski_isSavingFileValid(app.SavingFileEditField.Value);
                if ~isempty(errValidity)
                    errordlg(errValidity, 'Saving Error');
                else
                    varName = ['IliskiOutput_' datestr(now, 'yymmdd_HHMMSS')];
                    outputStruct.(varName) = app.data.lastComput;
                    
                    progressBar = uiprogressdlg(app.IliskiUIFigure, 'Title', 'Saving ...', ...
                        'Indeterminate', 'on');
                    if isfile(app.SavingFileEditField.Value)
                        save(app.SavingFileEditField.Value, '-struct', 'outputStruct', '-append');
                    else
                        save(app.SavingFileEditField.Value, '-struct', 'outputStruct');
                    end
                    close(progressBar);
                    assignin('base', varName, app.data.lastComput)
                    msgbox('Saving Completed.');
                end
                
            catch ME
                app.catchErrorAndInform(ME, 'Saving Error');
                return
            end
        end

        % Value changed function: TotalSpinner
        function TotalSpinnerValueChanged(app, event)
            try
                value = app.TotalSpinner.Value;
            minimumDuration = app.BaselineStepSpinner.Value + app.UpStepSpinner.Value;
            
            if value < minimumDuration
                app.TotalSpinner.Value = minimumDuration;
                errorMsg = 'Total duration of the boxcar function should be at least equal to the sum of both the baseline and the up state. Value is corrected to this minimum.';
                errordlg(errorMsg, 'Boxcar function generator Error');
            end
            
            app.DisplayDataButtonPushed();
            catch ME
                app.catchErrorAndInform(ME, 'Boxcar function Error');
                return
            end
        end

        % Value changed function: UseStepCheckbox
        function UseStepCheckboxValueChanged(app, event)
            try
                value = app.UseStepCheckbox.Value;
            
            if value
                app.FromH5DataPath.Enable = 'off';
                app.FromH5FilePath.Enable = 'off';
                app.FromTextFilePath.Enable = 'off';
                app.CheckToSameAsFromH5File.Enable = 'off';
                app.SGolayPointsFromSpinner.Enable = 'off';
                app.MedianFilterFromSpinner.Enable = 'off';
                app.InterpolationMethodDropDown.Enable = 'off';
            else
                app.FromH5DataPath.Enable = 'on';
                app.FromH5FilePath.Enable = 'on';
                app.FromTextFilePath.Enable = 'on';
                app.CheckToSameAsFromH5File.Enable = 'on';
                app.SGolayPointsFromSpinner.Enable = 'on';
                app.MedianFilterFromSpinner.Enable = 'on';
                app.InterpolationMethodDropDown.Enable = 'on';
            end
            
            app.updateEnableLoadDisplayButton();
            
            if strcmp(app.DisplayDataButton.Enable, 'on') % Load back again only if it is possible
                app.DisplayDataButtonPushed();
            end
            
            catch ME
                app.catchErrorAndInform(ME, 'Boxcar function Error');
                return
            end
        end

        % Value changed function: SGolayPointsToSpinner
        function SGolayPointsToSpinnerValueChanged(app, event)
            value = app.SGolayPointsToSpinner.Value;
            
            % Checking if the SGolay parameter is an odd number, as it should be. Adding 1 and error dlg otherwise
            if mod(value, 2) == 0 && value ~= 0 % Even number
                app.SGolayPointsToSpinner.Value = value + 1;
                errorMsg = {'SGolay filter requires an odd number of points as the frame length parameter. Value is corrected by adding 1.'; 'See https://fr.mathworks.com/help/signal/ref/sgolayfilt.html for details.'};
                errordlg(errorMsg, 'SGolay filter Error', 'error');
            end
            
            % Changing the step to avoid even numbers
            if value == 0
                app.SGolayPointsToSpinner.Step = 1;
            else
                app.SGolayPointsToSpinner.Step = 2;
            end
        end

        % Value changed function: SGolayPointsFromSpinner
        function SGolayPointsFromSpinnerValueChanged(app, event)
            value = app.SGolayPointsFromSpinner.Value;
            
            % Checking if the SGolay parameter is an odd number, as it should be. Adding 1 and error dlg otherwise
            if mod(value, 2) == 0 && value ~= 0 % Even number
                app.SGolayPointsFromSpinner.Value = value + 1;
                errorMsg = {'SGolay filter requires an odd number of points as the frame length parameter. Value is corrected by adding 1.'; 'See https://fr.mathworks.com/help/signal/ref/sgolayfilt.html for details.'};
                errordlg(errorMsg, 'SGolay filter Error', 'error');
            end
            
            % Changing the step to avoid even numbers
            if value == 0
                app.SGolayPointsFromSpinner.Step = 1;
            else
                app.SGolayPointsFromSpinner.Step = 2;
            end
        end

        % Callback function: FromH5FilePath
        function FromH5FilePathValueChanged(app, event)

            try
                value = app.FromH5FilePath.Value;
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromH5File.Value
                app.ToH5FilePath.Value = app.FromH5FilePath.Value;
            end
            app.LoadFromHDF5FilesButton.Value = 1;
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Value changed function: ToH5FilePath
        function ToH5FilePathValueChanged(app, event)
            try
                value = app.ToH5FilePath.Value;
            app.LoadFromHDF5FilesButton.Value = 1;
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Value changed function: FromH5DataPath
        function FromH5DataPathValueChanged(app, event)
            try
                value = app.FromH5DataPath.Value;
            app.LoadFromHDF5FilesButton.Value = 1;
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Value changed function: ToH5DataPath
        function ToH5DataPathValueChanged(app, event)
            try
            value = app.ToH5DataPath.Value;
            app.LoadFromHDF5FilesButton.Value = 1;
            app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file Error');
                return
            end
        end

        % Value changed function: paramFunctionStrEditField
        function paramFunctionStrEditFieldValueChanged(app, event)
            try
                value = app.paramFunctionStrEditField.Value;
                app.updateParametersTab();
            catch ME
                app.catchErrorAndInform(ME, 'Updating parameters tab Error');
                return
            end
        end

        % Button pushed function: UseLastComputParamsAsInitialButton
        function UseLastComputParamsAsInitialButtonPushed(app, event)
            try
                app.SetParamsTable.Data(:, 2) = table(app.ComputedParamsTable.Data);
            catch ME
                app.catchErrorAndInform(ME, 'Updating parameters tab Error');
                return
            end
        end

        % Button pushed function: GoSavingPanelButton
        function GoSavingPanelButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
        end

        % Value changed function: deltaT
        function deltaTValueChanged(app, event)
            try
                value = app.deltaT.Value;
                if app.UseStepCheckbox.Value
                    app.DisplayDataButtonPushed();
                end
            catch ME
                app.catchErrorAndInform(ME, 'Sampling time Error');
                return
            end
        end

        % Value changing function: SavingFileEditField
        function SavingFileEditFieldValueChanging(app, event)
            try
                changingValue = event.Value;
                
                app.checkValiditySavingFile(changingValue);
            catch ME
                app.catchErrorAndInform(ME, 'Saving file Error');
                return
            end
        end

        % Button pushed function: LoadResultFileButton
        function LoadResultFileButtonPushed(app, event)
            try
                results = load(app.SavingFileEditField.Value);
                
                fieldNames = fieldnames(results);
                idxFields = find(cell2mat(strfind(fieldNames, 'IliskiOutput_')));
                
                if length(idxFields) > 0
                    app.ResultsFromFileDropDown.Items = fieldNames(idxFields);
                    app.ResultsFromFileDropDown.Enable = 'on'; app.ResultsFromFileDropDownLabel.Enable = 'on';
                    app.loadedResults = results;
                    app.fillLoadedResults();
                else
                    errordlg('There is no saved Iliski computed results in this file.', 'Saved Results Loading Error');
                end
            catch ME
                app.catchErrorAndInform(ME, 'Loading results Error');
                return
            end
        end

        % Value changed function: CheckShowInitialTF
        function CheckShowInitialTFValueChanged(app, event)
            try
                value = app.CheckShowInitialTF.Value;
                % Just update the plots
                app.updatePlots();
            catch ME
                app.catchErrorAndInform(ME, 'Update initial TF Error');
                return
            end
        end

        % Value changed function: ResultsFromFileDropDown
        function ResultsFromFileDropDownValueChanged(app, event)
            try
                value = app.ResultsFromFileDropDown.Value;
                app.fillLoadedResults();
            catch ME
                app.catchErrorAndInform(ME, 'Results file loading Error');
                return
            end
        end

        % Callback function: FromTextFilePath
        function FromTextFilePathValueChanged(app, event)
            try
                value = app.FromTextFilePath.Value;
                
                app.LoadFromTextFilesButton.Value = 1;
                app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file error');
                return
            end
        end

        % Callback function: ToTextFilePath
        function ToTextFilePathValueChanged(app, event)
            try
                value = app.ToTextFilePath.Value;
                app.LoadFromTextFilesButton.Value = 1;
                app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Input file error');
                return
            end
        end

        % Button pushed function: ChooseFromTextFileButton
        function ChooseFromTextFileButtonPushed(app, event)
            try
                [fileName, pathFile] = uigetfile('*', 'Select the From data file');
                app.FromTextFilePath.Value = [pathFile fileName];
                app.LoadFromTextFilesButton.Value = 1;
                
                app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Loading file Error');
                return
            end
        end

        % Button pushed function: ChooseToTextFileButton
        function ChooseToTextFileButtonPushed(app, event)
            try
                [fileName, pathFile] = uigetfile('*', 'Select the To data file');
                app.ToTextFilePath.Value = [pathFile fileName];
                app.LoadFromTextFilesButton.Value = 1;
                
                app.updateEnableLoadDisplayButton();
            catch ME
                app.catchErrorAndInform(ME, 'Loading file Error');
                return
            end
        end

        % Button pushed function: InformationsButton
        function InformationsButtonPushed(app, event)
            try
                msg = fileread(app.INFO_TEXT_FILE);
                mode = struct(); mode.Interpreter = 'tex'; mode.WindowStyle = 'non-modal';
                msgbox(msg, 'Loading data from text files', 'help', mode);
            catch ME
                app.catchErrorAndInform(ME, 'Information file Error');
                return
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            try
                msg = fileread(app.ABOUT_FILE);
                msgbox(msg, 'About Iliski', 'help');
            catch ME
                app.catchErrorAndInform(ME, 'About file Error');
                return
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            try
                msg = fileread(app.HELP_FILE);
                msgbox(msg, 'Help for Iliski', 'help');
            catch ME
                app.catchErrorAndInform(ME, 'About file Error');
                return
            end
        end

        % Button pushed function: ButtonChooseXLSFile
        function ButtonChooseXLSFilePushed(app, event)
            try
                [fileName, pathFile] = uiputfile({'*.xlsx;*.xls', 'Excel Files (*.xls, *.xlsx)'}, 'Select an (in)existent file to save your results');
                if fileName ~= 0
                    app.SavingXLSFileEditField.Value = [pathFile fileName];
                    app.checkValiditySavingXLSFile(app.SavingXLSFileEditField.Value);
                end
            catch ME
                app.catchErrorAndInform(ME, 'Saving file Error');
                return
            end
        end

        % Button pushed function: SaveXLSButton
        function SaveXLSButtonPushed(app, event)
            % Checking if all parameter fields are filled
            try
                errValidity = Iliski_isSavingXLSFileValid(app.SavingXLSFileEditField.Value);
                if ~isempty(errValidity)
                    errordlg(errValidity, 'Saving Error');
                else
                    varName = ['IliskiOutput_' datestr(now, 'yymmdd_HHMMSS')];
                    outputStruct = app.data.lastComput;
                    
                    progressBar = uiprogressdlg(app.IliskiUIFigure, 'Title', 'Saving ...', ...
                        'Indeterminate', 'on');
                    Iliski_saveResultsAsXLS(outputStruct, app.SavingXLSFileEditField.Value);
                    close(progressBar);
                    assignin('base', varName, app.data.lastComput)
                    msgbox('Saving Completed.');
                end
                
            catch ME
                app.catchErrorAndInform(ME, 'Saving Error');
                return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IliskiUIFigure = uifigure;
            app.IliskiUIFigure.Name = 'Iliski';
            app.IliskiUIFigure.CloseRequestFcn = createCallbackFcn(app, @IliskiUIFigureCloseRequest, true);
            app.DisplayDataButton = uibutton(app.IliskiUIFigure, 'push');
            app.DisplayDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.DisplayDataButton.Text = {'Load '; '& '; 'Display Data'};
            app.PlotOutFiguresButton = uibutton(app.IliskiUIFigure, 'push');
            app.PlotOutFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOutFiguresButtonPushed, true);
            app.PlotOutFiguresButton.Text = 'Plot Out Figures';
            app.ComputeButton = uibutton(app.IliskiUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute TF';
            app.CheckShowLegend = uicheckbox(app.IliskiUIFigure);
            app.CheckShowLegend.ValueChangedFcn = createCallbackFcn(app, @CheckShowLegendValueChanged, true);
            app.CheckShowLegend.Text = 'Show legend';
            app.CheckShowLegend.Value = true;
            app.CheckShowTreatedData = uicheckbox(app.IliskiUIFigure);
            app.CheckShowTreatedData.ValueChangedFcn = createCallbackFcn(app, @CheckShowTreatedDataValueChanged, true);
            app.CheckShowTreatedData.Enable = 'off';
            app.CheckShowTreatedData.Text = 'Show treated data';
            app.PlotRunSpinnerLabel = uilabel(app.IliskiUIFigure);
            app.PlotRunSpinnerLabel.Enable = 'off';
            app.PlotRunSpinnerLabel.Text = 'Plot Run';
            app.PlotRunSpinner = uispinner(app.IliskiUIFigure);
            app.PlotRunSpinner.Limits = [1 Inf];
            app.PlotRunSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotRunSpinnerValueChanged, true);
            app.PlotRunSpinner.Enable = 'off';
            app.PlotRunSpinner.Value = 1;
            app.ResidualvalueEditFieldLabel = uilabel(app.IliskiUIFigure);
            app.ResidualvalueEditFieldLabel.Enable = 'off';
            app.ResidualvalueEditFieldLabel.Text = 'Residual value';
            app.ResidualvalueEditField = uieditfield(app.IliskiUIFigure, 'numeric');
            app.ResidualvalueEditField.ValueDisplayFormat = '%.5f';
            app.ResidualvalueEditField.Editable = 'off';
            app.ResidualvalueEditField.Enable = 'off';
            app.PearsonvalueEditFieldLabel = uilabel(app.IliskiUIFigure);
            app.PearsonvalueEditFieldLabel.Enable = 'off';
            app.PearsonvalueEditFieldLabel.Text = 'Pearson value';
            app.PearsonvalueEditField = uieditfield(app.IliskiUIFigure, 'numeric');
            app.PearsonvalueEditField.ValueDisplayFormat = '%.5f';
            app.PearsonvalueEditField.Editable = 'off';
            app.PearsonvalueEditField.Enable = 'off';
            app.DataTFPanel = uipanel(app.IliskiUIFigure);
            app.DataTFPanel.Title = 'Data & TF';
            app.FromPlot = uiaxes(app.DataTFPanel);
            title(app.FromPlot, 'From')
            xlabel(app.FromPlot, 'Time (s)')
            app.ToPlot = uiaxes(app.DataTFPanel);
            title(app.ToPlot, 'To')
            xlabel(app.ToPlot, 'Time (s)')
            app.TFPlot = uiaxes(app.DataTFPanel);
            title(app.TFPlot, 'TF')
            xlabel(app.TFPlot, 'Time (s)')
            app.AboutButton = uibutton(app.IliskiUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.HelpButton = uibutton(app.IliskiUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.TabGroup = uitabgroup(app.IliskiUIFigure);
            app.PretreatmentTab = uitab(app.TabGroup);
            app.PretreatmentTab.Title = 'Pre-treatment';
            app.ToPanel = uipanel(app.PretreatmentTab);
            app.ToPanel.Title = 'To';
            app.SGolayFilterptsLabel_2 = uilabel(app.ToPanel);
            app.SGolayFilterptsLabel_2.Text = 'SGolay Filter (pts)';
            app.SGolayPointsToSpinner = uispinner(app.ToPanel);
            app.SGolayPointsToSpinner.Limits = [0 Inf];
            app.SGolayPointsToSpinner.ValueChangedFcn = createCallbackFcn(app, @SGolayPointsToSpinnerValueChanged, true);
            app.MedianFilterptsLabel_2 = uilabel(app.ToPanel);
            app.MedianFilterptsLabel_2.Text = 'Median Filter (pts)';
            app.MedianFilterToSpinner = uispinner(app.ToPanel);
            app.MedianFilterToSpinner.Limits = [0 Inf];
            app.FromBoxcargeneratorPanel = uipanel(app.PretreatmentTab);
            app.FromBoxcargeneratorPanel.Title = 'From : Boxcar generator ';
            app.UseStepCheckbox = uicheckbox(app.FromBoxcargeneratorPanel);
            app.UseStepCheckbox.ValueChangedFcn = createCallbackFcn(app, @UseStepCheckboxValueChanged, true);
            app.UseStepCheckbox.Text = 'Use boxcar as From signal';
            app.BaselinesSpinnerLabel = uilabel(app.FromBoxcargeneratorPanel);
            app.BaselinesSpinnerLabel.Text = 'Baseline (s)';
            app.BaselineStepSpinner = uispinner(app.FromBoxcargeneratorPanel);
            app.BaselineStepSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.BaselineStepSpinner.Limits = [0 Inf];
            app.BaselineStepSpinner.Value = 10;
            app.UpsSpinnerLabel = uilabel(app.FromBoxcargeneratorPanel);
            app.UpsSpinnerLabel.Text = 'Up (s)';
            app.UpStepSpinner = uispinner(app.FromBoxcargeneratorPanel);
            app.UpStepSpinner.ValueChangingFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.UpStepSpinner.Limits = [0 Inf];
            app.UpStepSpinner.Value = 5;
            app.TotalsSpinnerLabel = uilabel(app.FromBoxcargeneratorPanel);
            app.TotalsSpinnerLabel.Text = 'Total (s)';
            app.TotalSpinner = uispinner(app.FromBoxcargeneratorPanel);
            app.TotalSpinner.Limits = [0 Inf];
            app.TotalSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalSpinnerValueChanged, true);
            app.TotalSpinner.Value = 30;
            app.FromPanel = uipanel(app.PretreatmentTab);
            app.FromPanel.Title = 'From';
            app.SGolayFilterptsLabel = uilabel(app.FromPanel);
            app.SGolayFilterptsLabel.Text = 'SGolay Filter (pts)';
            app.SGolayPointsFromSpinner = uispinner(app.FromPanel);
            app.SGolayPointsFromSpinner.Limits = [0 Inf];
            app.SGolayPointsFromSpinner.ValueChangedFcn = createCallbackFcn(app, @SGolayPointsFromSpinnerValueChanged, true);
            app.MedianFilterptsLabel = uilabel(app.FromPanel);
            app.MedianFilterptsLabel.Text = 'Median Filter (pts)';
            app.MedianFilterFromSpinner = uispinner(app.FromPanel);
            app.MedianFilterFromSpinner.Limits = [0 Inf];
            app.InterpolationMethodDropDownLabel = uilabel(app.FromPanel);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.InterpolationMethodDropDown = uidropdown(app.FromPanel);
            app.InterpolationMethodDropDown.Items = {'spline', 'pchip', 'linear'};
            app.InterpolationMethodDropDown.Value = 'spline';
            app.SamplingtimemsLabel = uilabel(app.FromPanel);
            app.SamplingtimemsLabel.Text = 'Sampling time (ms)';
            app.deltaT = uispinner(app.FromPanel);
            app.deltaT.Step = 10;
            app.deltaT.Limits = [1 Inf];
            app.deltaT.ValueChangedFcn = createCallbackFcn(app, @deltaTValueChanged, true);
            app.deltaT.Value = 100;
            app.Settingtheparametersvalueto0cancelthefilteringLabel = uilabel(app.PretreatmentTab);
            app.Settingtheparametersvalueto0cancelthefilteringLabel.Text = 'Setting the parameters value to 0 cancel the filtering.';
            app.BothPanel = uipanel(app.PretreatmentTab);
            app.BothPanel.Title = 'Both';
            app.RangefromsLabel = uilabel(app.BothPanel);
            app.RangefromsLabel.Text = 'Range from (s)';
            app.rangeDataBegin = uispinner(app.BothPanel);
            app.rangeDataBegin.Step = 0.5;
            app.rangeDataBegin.Value = 5;
            app.toLabel = uilabel(app.BothPanel);
            app.toLabel.Text = 'to ';
            app.rangeDataEnd = uispinner(app.BothPanel);
            app.rangeDataEnd.Step = 0.5;
            app.rangeDataEnd.Value = 27;
            app.TFComputationTab = uitab(app.TabGroup);
            app.TFComputationTab.Title = 'TF Computation';
            app.TFComputationPanel = uipanel(app.TFComputationTab);
            app.TFComputationPanel.Title = 'TF Computation';
            app.DurationsLabel = uilabel(app.TFComputationPanel);
            app.DurationsLabel.Text = 'Duration (s)';
            app.durationTF = uispinner(app.TFComputationPanel);
            app.durationTF.Step = 0.5;
            app.durationTF.Value = 15;
            app.NumberofrunsSpinnerLabel = uilabel(app.TFComputationPanel);
            app.NumberofrunsSpinnerLabel.Text = 'Number of runs';
            app.NumberofrunsSpinner = uispinner(app.TFComputationPanel);
            app.NumberofrunsSpinner.Limits = [1 Inf];
            app.NumberofrunsSpinner.Value = 1;
            app.FunctionDropDownLabel = uilabel(app.TFComputationPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.TFComputationPanel);
            app.FunctionDropDown.Items = {};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = {};
            app.AlgorithmDropDownLabel = uilabel(app.TFComputationPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.TFComputationPanel);
            app.AlgorithmDropDown.Items = {'Simulated Annealing', 'FMinUnc', 'FMinSearch', 'FMinCon'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'Simulated Annealing';
            app.twiceOpt = uicheckbox(app.TFComputationPanel);
            app.twiceOpt.ValueChangedFcn = createCallbackFcn(app, @twiceOptValueChanged, true);
            app.twiceOpt.Text = {'Simulated and '; 'then FMinUnc'};
            app.UseLastComputParamsAsInitialButton = uibutton(app.TFComputationPanel, 'push');
            app.UseLastComputParamsAsInitialButton.ButtonPushedFcn = createCallbackFcn(app, @UseLastComputParamsAsInitialButtonPushed, true);
            app.UseLastComputParamsAsInitialButton.Enable = 'off';
            app.UseLastComputParamsAsInitialButton.Text = 'Use computed parameters as initial';
            app.GoSavingPanelButton = uibutton(app.TFComputationPanel, 'push');
            app.GoSavingPanelButton.ButtonPushedFcn = createCallbackFcn(app, @GoSavingPanelButtonPushed, true);
            app.GoSavingPanelButton.Enable = 'off';
            app.GoSavingPanelButton.Text = 'Go to Saving';
            app.AuditoryCueCheckBox = uicheckbox(app.TFComputationPanel);
            app.AuditoryCueCheckBox.Text = 'GONG';
            app.AuditoryCueCheckBox.Value = true;
            app.SetParamsTable = uitable(app.TFComputationTab);
            app.SetParamsTable.ColumnName = {'Parameter #'; 'Starting Value'; 'Lower bound'; 'Upper bound'};
            app.SetParamsTable.RowName = {};
            app.SetParamsTable.ColumnEditable = [false true true true];
            app.ComputedParamsTable = uitable(app.TFComputationTab);
            app.ComputedParamsTable.ColumnName = {'Computed'};
            app.ComputedParamsTable.RowName = {};
            app.EditFieldLabel = uilabel(app.TFComputationTab);
            app.EditFieldLabel.Text = '';
            app.paramFunctionStrEditField = uieditfield(app.TFComputationTab, 'text');
            app.paramFunctionStrEditField.ValueChangedFcn = createCallbackFcn(app, @paramFunctionStrEditFieldValueChanged, true);
            app.paramFunctionStrEditField.Editable = 'off';
            app.SavingLoadingresultsTab = uitab(app.TabGroup);
            app.SavingLoadingresultsTab.Title = 'Saving & Loading results';
            app.TabGroup2 = uitabgroup(app.SavingLoadingresultsTab);
            app.MATfileTab = uitab(app.TabGroup2);
            app.MATfileTab.Title = 'MAT file';
            app.ResultsFromFileDropDownLabel = uilabel(app.MATfileTab);
            app.ResultsFromFileDropDownLabel.Enable = 'off';
            app.ResultsFromFileDropDownLabel.Text = 'Results to load';
            app.ResultsFromFileDropDown = uidropdown(app.MATfileTab);
            app.ResultsFromFileDropDown.Items = {''};
            app.ResultsFromFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsFromFileDropDownValueChanged, true);
            app.ResultsFromFileDropDown.Enable = 'off';
            app.ResultsFromFileDropDown.Value = '';
            app.LoadResultFileButton = uibutton(app.MATfileTab, 'push');
            app.LoadResultFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultFileButtonPushed, true);
            app.LoadResultFileButton.Enable = 'off';
            app.LoadResultFileButton.Text = 'Load file';
            app.FileEditFieldLabel = uilabel(app.MATfileTab);
            app.FileEditFieldLabel.Text = 'File';
            app.SavingFileEditField = uieditfield(app.MATfileTab, 'text');
            app.SavingFileEditField.ValueChangingFcn = createCallbackFcn(app, @SavingFileEditFieldValueChanging, true);
            app.SavingFileEditField.Value = 'C:\Users\ali-k\Desktop\test.mat';
            app.ButtonChooseSaveFile = uibutton(app.MATfileTab, 'push');
            app.ButtonChooseSaveFile.ButtonPushedFcn = createCallbackFcn(app, @ChooseSaveFileButtonPushed, true);
            app.ButtonChooseSaveFile.Text = 'Browse';
            app.SavingInfoLabel = uilabel(app.MATfileTab);
            app.SavingInfoLabel.Text = {'If the file already exists, the current computation '; 'output will be added to the file as a structure named '; 'IliskiOutput_YYYYMMDD_HHMMSS (although'; 'your system may warn you it will delete the file). '; 'Otherwise, the structure will be saved in a new file.'};
            app.SaveButton = uibutton(app.MATfileTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.XLSfileTab = uitab(app.TabGroup2);
            app.XLSfileTab.Title = 'XLS file';
            app.FileEditFieldLabel_2 = uilabel(app.XLSfileTab);
            app.FileEditFieldLabel_2.Text = 'File';
            app.SavingXLSFileEditField = uieditfield(app.XLSfileTab, 'text');
            app.ButtonChooseXLSFile = uibutton(app.XLSfileTab, 'push');
            app.ButtonChooseXLSFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseXLSFilePushed, true);
            app.ButtonChooseXLSFile.Text = 'Browse';
            app.SaveXLSButton = uibutton(app.XLSfileTab, 'push');
            app.SaveXLSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveXLSButtonPushed, true);
            app.SaveXLSButton.Enable = 'off';
            app.SaveXLSButton.Text = 'Save';
            app.CheckShowInitialTF = uicheckbox(app.IliskiUIFigure);
            app.CheckShowInitialTF.ValueChangedFcn = createCallbackFcn(app, @CheckShowInitialTFValueChanged, true);
            app.CheckShowInitialTF.Enable = 'off';
            app.CheckShowInitialTF.Text = 'Show initial TF';
            app.CheckShowInitialTF.Value = true;
            app.LoadingFilesTab = uitabgroup(app.IliskiUIFigure);
            app.TextFilesTab = uitab(app.LoadingFilesTab);
            app.TextFilesTab.Title = 'Text Files';
            app.FromFileEditFieldLabel = uilabel(app.TextFilesTab);
            app.FromFileEditFieldLabel.Text = 'From File';
            app.FromTextFilePath = uieditfield(app.TextFilesTab, 'text');
            app.FromTextFilePath.ValueChangedFcn = createCallbackFcn(app, @FromTextFilePathValueChanged, true);
            app.FromTextFilePath.ValueChangingFcn = createCallbackFcn(app, @FromTextFilePathValueChanged, true);
            app.FromTextFilePath.Value = 'C:\Users\Ali-Kemal Aydin\OneDrive\PhD_Charpak\Iliski_TF\FromData.txt';
            app.ToFileEditFieldLabel = uilabel(app.TextFilesTab);
            app.ToFileEditFieldLabel.Text = 'To File';
            app.ToTextFilePath = uieditfield(app.TextFilesTab, 'text');
            app.ToTextFilePath.ValueChangedFcn = createCallbackFcn(app, @ToTextFilePathValueChanged, true);
            app.ToTextFilePath.ValueChangingFcn = createCallbackFcn(app, @ToTextFilePathValueChanged, true);
            app.ToTextFilePath.Value = 'C:\Users\Ali-Kemal Aydin\OneDrive\PhD_Charpak\Iliski_TF\ToData.txt';
            app.ChooseFromTextFileButton = uibutton(app.TextFilesTab, 'push');
            app.ChooseFromTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFromTextFileButtonPushed, true);
            app.ChooseFromTextFileButton.Text = 'Browse';
            app.ChooseToTextFileButton = uibutton(app.TextFilesTab, 'push');
            app.ChooseToTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseToTextFileButtonPushed, true);
            app.ChooseToTextFileButton.Text = 'Browse';
            app.InformationsButton = uibutton(app.TextFilesTab, 'push');
            app.InformationsButton.ButtonPushedFcn = createCallbackFcn(app, @InformationsButtonPushed, true);
            app.InformationsButton.Text = 'Informations';
            app.HDF5FilesTab = uitab(app.LoadingFilesTab);
            app.HDF5FilesTab.Title = 'HDF5 Files';
            app.ChooseFromH5FileButton = uibutton(app.HDF5FilesTab, 'push');
            app.ChooseFromH5FileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFromH5FileButtonPushed, true);
            app.ChooseFromH5FileButton.Text = 'Browse';
            app.ChooseToH5FileButton = uibutton(app.HDF5FilesTab, 'push');
            app.ChooseToH5FileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseToH5FileButtonPushed, true);
            app.ChooseToH5FileButton.Text = 'Browse';
            app.CheckToSameAsFromH5File = uicheckbox(app.HDF5FilesTab);
            app.CheckToSameAsFromH5File.ValueChangedFcn = createCallbackFcn(app, @CheckToSameAsFromH5FileValueChanged, true);
            app.CheckToSameAsFromH5File.Text = 'Same';
            app.FromHDF5EditFieldLabel = uilabel(app.HDF5FilesTab);
            app.FromHDF5EditFieldLabel.Text = 'From HDF5';
            app.FromH5FilePath = uieditfield(app.HDF5FilesTab, 'text');
            app.FromH5FilePath.ValueChangedFcn = createCallbackFcn(app, @FromH5FilePathValueChanged, true);
            app.FromH5FilePath.ValueChangingFcn = createCallbackFcn(app, @FromH5FilePathValueChanged, true);
            app.FromH5FilePath.Value = 'C:\Users\ali-k\OneDrive\PhD_Charpak\Iliski_TF\iliski\XV4.h5';
            app.ToHDF5EditFieldLabel = uilabel(app.HDF5FilesTab);
            app.ToHDF5EditFieldLabel.Text = 'To HDF5';
            app.ToH5FilePath = uieditfield(app.HDF5FilesTab, 'text');
            app.ToH5FilePath.ValueChangedFcn = createCallbackFcn(app, @ToH5FilePathValueChanged, true);
            app.ToH5FilePath.Value = 'C:\Users\ali-k\OneDrive\PhD_Charpak\Iliski_TF\iliski\XV4.h5';
            app.PathFromEditFieldLabel = uilabel(app.HDF5FilesTab);
            app.PathFromEditFieldLabel.Text = 'Path - From';
            app.FromH5DataPath = uieditfield(app.HDF5FilesTab, 'text');
            app.FromH5DataPath.ValueChangedFcn = createCallbackFcn(app, @FromH5DataPathValueChanged, true);
            app.FromH5DataPath.Value = '/Delta/Ca/ET_200mV_5sec/avg';
            app.PathToEditFieldLabel = uilabel(app.HDF5FilesTab);
            app.PathToEditFieldLabel.Text = 'Path - To';
            app.ToH5DataPath = uieditfield(app.HDF5FilesTab, 'text');
            app.ToH5DataPath.ValueChangedFcn = createCallbackFcn(app, @ToH5DataPathValueChanged, true);
            app.ToH5DataPath.Value = '/DeltaOverBSL/RBC/ET_200mV_5sec/avg';
            app.LoadfromButtonGroup = uibuttongroup(app.IliskiUIFigure);
            app.LoadfromButtonGroup.Title = 'Load from';
            app.LoadFromTextFilesButton = uiradiobutton(app.LoadfromButtonGroup);
            app.LoadFromTextFilesButton.Text = 'Text';
            app.LoadFromTextFilesButton.Value = true;
            app.LoadFromHDF5FilesButton = uiradiobutton(app.LoadfromButtonGroup);
            app.LoadFromHDF5FilesButton.Text = 'HDF5';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Full

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IliskiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IliskiUIFigure)
        end
    end
end