classdef SetParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetTFParameters           matlab.ui.Figure
        P1ValuePanel              matlab.ui.container.Panel
        StartingSpinnerLabel      matlab.ui.control.Label
        StartingP1                matlab.ui.control.Spinner
        MinimumSpinnerLabel       matlab.ui.control.Label
        MinimumP1                 matlab.ui.control.Spinner
        MaximumSpinnerLabel       matlab.ui.control.Label
        MaximumP1                 matlab.ui.control.Spinner
        P2ValuePanel              matlab.ui.container.Panel
        StartingSpinner_2Label    matlab.ui.control.Label
        StartingP2                matlab.ui.control.Spinner
        MinimumSpinner_2Label     matlab.ui.control.Label
        MinimumP2                 matlab.ui.control.Spinner
        MaximumSpinner_2Label     matlab.ui.control.Label
        MaximumP2                 matlab.ui.control.Spinner
        P3ValuePanel              matlab.ui.container.Panel
        StartingSpinner_3Label    matlab.ui.control.Label
        StartingP3                matlab.ui.control.Spinner
        MinimumSpinner_3Label     matlab.ui.control.Label
        MinimumP3                 matlab.ui.control.Spinner
        MaximumSpinner_3Label     matlab.ui.control.Label
        MaximumP3                 matlab.ui.control.Spinner
        P4ValuePanel              matlab.ui.container.Panel
        StartingSpinner_4Label    matlab.ui.control.Label
        StartingP4                matlab.ui.control.Spinner
        MinimumSpinner_4Label     matlab.ui.control.Label
        MinimumP4                 matlab.ui.control.Spinner
        MaximumSpinner_4Label     matlab.ui.control.Label
        MaximumP4                 matlab.ui.control.Spinner
        OKButton                  matlab.ui.control.Button
        P5ValuePanel              matlab.ui.container.Panel
        StartingSpinner_3Label_2  matlab.ui.control.Label
        StartingP5                matlab.ui.control.Spinner
        MinimumSpinner_3Label_2   matlab.ui.control.Label
        MinimumP5                 matlab.ui.control.Spinner
        MaximumSpinner_3Label_2   matlab.ui.control.Label
        MaximumP5                 matlab.ui.control.Spinner
        P6ValuePanel              matlab.ui.container.Panel
        StartingSpinner_3Label_3  matlab.ui.control.Label
        StartingP6                matlab.ui.control.Spinner
        MinimumSpinner_3Label_3   matlab.ui.control.Label
        MinimumP6                 matlab.ui.control.Spinner
        MaximumSpinner_3Label_3   matlab.ui.control.Label
        MaximumP6                 matlab.ui.control.Spinner
        equaImgButton             matlab.ui.control.Button
        equaLabel                 matlab.ui.control.Label
        SetDefaultButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp = 0; % Description
        params = struct(); % Description
    end
    
    methods (Access = private)
        
        
        function setParams(app)
            if isequal(app.params, struct()) % if the struct is empty and thus default value
                app.SetDefaultButtonPushed();
            else 
                app.StartingP1.Value = app.params.p1.value;
                app.MinimumP1.Value = app.params.p1.min;
                app.MaximumP1.Value = app.params.p1.max;
                
                app.StartingP2.Value = app.params.p2.value;
                app.MinimumP2.Value = app.params.p2.min;
                app.MaximumP2.Value = app.params.p2.max;
                
                app.StartingP3.Value = app.params.p3.value;
                app.MinimumP3.Value = app.params.p3.min;
                app.MaximumP3.Value = app.params.p3.max;
                
                app.StartingP4.Value = app.params.p4.value;
                app.MinimumP4.Value = app.params.p4.min;
                app.MaximumP4.Value = app.params.p4.max;
                
                app.StartingP5.Value = app.params.p5.value;
                app.MinimumP5.Value = app.params.p5.min;
                app.MaximumP5.Value = app.params.p5.max;
                
                app.StartingP6.Value = app.params.p6.value;
                app.MinimumP6.Value = app.params.p6.min;
                app.MaximumP6.Value = app.params.p6.max;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, params)
            app.callingApp = mainApp;
            
            app.params = params;
            app.setParams();
            
            %setting the equation image and label right
            if strcmp(app.callingApp.FunctionDropDown.Value, '1-Gamma')
                app.equaLabel.Text = '1-Gamma transfer function';
                app.equaImgButton.Icon = './Equa_Gamma.jpg';
                
                app.StartingP5.Enable = 'off';
                app.MinimumP5.Enable = 'off';
                app.MaximumP5.Enable = 'off';
                
                app.StartingP6.Enable = 'off';
                app.MinimumP6.Enable = 'off';
                app.MaximumP6.Enable = 'off';
                
            elseif strcmp(app.callingApp.FunctionDropDown.Value, '2-Inverse Logit')
                app.equaLabel.Text = '2-Inverse Logit transfer function';
                app.equaImgButton.Icon = './Equa_Logit.jpg';
                
                app.StartingP5.Enable = 'on';
                app.MinimumP5.Enable = 'on';
                app.MaximumP5.Enable = 'on';
                
                app.StartingP6.Enable = 'on';
                app.MinimumP6.Enable = 'on';
                app.MaximumP6.Enable = 'on';
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.params.p1.value = app.StartingP1.Value;
            app.params.p1.min = app.MinimumP1.Value;
            app.params.p1.max = app.MaximumP1.Value;
            
            app.params.p2.value = app.StartingP2.Value;
            app.params.p2.min = app.MinimumP2.Value;
            app.params.p2.max = app.MaximumP2.Value;
            
            app.params.p3.value = app.StartingP3.Value;
            app.params.p3.min = app.MinimumP3.Value;
            app.params.p3.max = app.MaximumP3.Value;
            
            app.params.p4.value = app.StartingP4.Value;
            app.params.p4.min = app.MinimumP4.Value;
            app.params.p4.max = app.MaximumP4.Value;
            
            app.params.p5.value = app.StartingP5.Value;
            app.params.p5.min = app.MinimumP5.Value;
            app.params.p5.max = app.MaximumP5.Value;
            
            app.params.p6.value = app.StartingP6.Value;
            app.params.p6.min = app.MinimumP6.Value;
            app.params.p6.max = app.MaximumP6.Value;
            
            app.callingApp.updateParamsDialog(app.params);
            delete(app);
        end

        % Close request function: SetTFParameters
        function SetTFParametersCloseRequest(app, event)
            try
                app.callingApp.updateParamsDialog();
            end
            delete(app);
        end

        % Button pushed function: SetDefaultButton
        function SetDefaultButtonPushed(app, event)
            if strcmp(app.callingApp.FunctionDropDown.Value, '1-Gamma')
                app.StartingP1.Value = 6;
                app.MinimumP1.Value = 0.001;
                app.MaximumP1.Value = 10;
                
                app.StartingP2.Value = 1;
                app.MinimumP2.Value = 0.001;
                app.MaximumP2.Value = 10;
                
                app.StartingP3.Value = 0.001;
                app.MinimumP3.Value = 0.001;
                app.MaximumP3.Value = 10;
                
                app.StartingP4.Value = 1;
                app.MinimumP4.Value = 0.001;
                app.MaximumP4.Value = 10;
                
                app.StartingP5.Value = 1;
                app.MinimumP5.Value = 1;
                app.MaximumP5.Value = 10;
                
                app.StartingP6.Value = 1;
                app.MinimumP6.Value = 0.001;
                app.MaximumP6.Value = 10;
                
            elseif strcmp(app.callingApp.FunctionDropDown.Value, '2-Inverse Logit')
                app.StartingP1.Value = 2;
                app.MinimumP1.Value = 0.001;
                app.MaximumP1.Value = 10;
                
                app.StartingP2.Value = 4;
                app.MinimumP2.Value = 0.001;
                app.MaximumP2.Value = 10;
                
                app.StartingP3.Value = 1;
                app.MinimumP3.Value = 0.001;
                app.MaximumP3.Value = 10;
                
                app.StartingP4.Value = 2;
                app.MinimumP4.Value = 0.001;
                app.MaximumP4.Value = 10;
                
                app.StartingP5.Value = 0.3;
                app.MinimumP5.Value = 0.001;
                app.MaximumP5.Value = 10;
                
                app.StartingP6.Value = 0.3;
                app.MinimumP6.Value = 0.001;
                app.MaximumP6.Value = 10;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetTFParameters = uifigure;
            app.SetTFParameters.Name = 'Set TF Parameters';
            app.SetTFParameters.CloseRequestFcn = createCallbackFcn(app, @SetTFParametersCloseRequest, true);
            app.P1ValuePanel = uipanel(app.SetTFParameters);
            app.P1ValuePanel.Title = 'P1 Value';
            app.StartingSpinnerLabel = uilabel(app.P1ValuePanel);
            app.StartingSpinnerLabel.Text = 'Starting ';
            app.StartingP1 = uispinner(app.P1ValuePanel);
            app.StartingP1.Value = 6;
            app.MinimumSpinnerLabel = uilabel(app.P1ValuePanel);
            app.MinimumSpinnerLabel.Text = 'Minimum';
            app.MinimumP1 = uispinner(app.P1ValuePanel);
            app.MinimumP1.Value = 0.001;
            app.MaximumSpinnerLabel = uilabel(app.P1ValuePanel);
            app.MaximumSpinnerLabel.Text = 'Maximum';
            app.MaximumP1 = uispinner(app.P1ValuePanel);
            app.P2ValuePanel = uipanel(app.SetTFParameters);
            app.P2ValuePanel.Title = 'P2 Value';
            app.StartingSpinner_2Label = uilabel(app.P2ValuePanel);
            app.StartingSpinner_2Label.Text = 'Starting ';
            app.StartingP2 = uispinner(app.P2ValuePanel);
            app.StartingP2.Value = 1;
            app.MinimumSpinner_2Label = uilabel(app.P2ValuePanel);
            app.MinimumSpinner_2Label.Text = 'Minimum';
            app.MinimumP2 = uispinner(app.P2ValuePanel);
            app.MinimumP2.Value = 0.001;
            app.MaximumSpinner_2Label = uilabel(app.P2ValuePanel);
            app.MaximumSpinner_2Label.Text = 'Maximum';
            app.MaximumP2 = uispinner(app.P2ValuePanel);
            app.P3ValuePanel = uipanel(app.SetTFParameters);
            app.P3ValuePanel.Title = 'P3 Value';
            app.StartingSpinner_3Label = uilabel(app.P3ValuePanel);
            app.StartingSpinner_3Label.Text = 'Starting ';
            app.StartingP3 = uispinner(app.P3ValuePanel);
            app.StartingP3.Value = 0.001;
            app.MinimumSpinner_3Label = uilabel(app.P3ValuePanel);
            app.MinimumSpinner_3Label.Text = 'Minimum';
            app.MinimumP3 = uispinner(app.P3ValuePanel);
            app.MinimumP3.Value = 0.001;
            app.MaximumSpinner_3Label = uilabel(app.P3ValuePanel);
            app.MaximumSpinner_3Label.Text = 'Maximum';
            app.MaximumP3 = uispinner(app.P3ValuePanel);
            app.P4ValuePanel = uipanel(app.SetTFParameters);
            app.P4ValuePanel.Title = 'P4 Value';
            app.StartingSpinner_4Label = uilabel(app.P4ValuePanel);
            app.StartingSpinner_4Label.Text = 'Starting ';
            app.StartingP4 = uispinner(app.P4ValuePanel);
            app.StartingP4.Value = 1;
            app.MinimumSpinner_4Label = uilabel(app.P4ValuePanel);
            app.MinimumSpinner_4Label.Text = 'Minimum';
            app.MinimumP4 = uispinner(app.P4ValuePanel);
            app.MinimumP4.Value = 0.001;
            app.MaximumSpinner_4Label = uilabel(app.P4ValuePanel);
            app.MaximumSpinner_4Label.Text = 'Maximum';
            app.MaximumP4 = uispinner(app.P4ValuePanel);
            app.OKButton = uibutton(app.SetTFParameters, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.P5ValuePanel = uipanel(app.SetTFParameters);
            app.P5ValuePanel.Title = 'P5 Value';
            app.StartingSpinner_3Label_2 = uilabel(app.P5ValuePanel);
            app.StartingSpinner_3Label_2.Text = 'Starting ';
            app.StartingP5 = uispinner(app.P5ValuePanel);
            app.StartingP5.Value = 0.001;
            app.MinimumSpinner_3Label_2 = uilabel(app.P5ValuePanel);
            app.MinimumSpinner_3Label_2.Text = 'Minimum';
            app.MinimumP5 = uispinner(app.P5ValuePanel);
            app.MinimumP5.Value = 0.001;
            app.MaximumSpinner_3Label_2 = uilabel(app.P5ValuePanel);
            app.MaximumSpinner_3Label_2.Text = 'Maximum';
            app.MaximumP5 = uispinner(app.P5ValuePanel);
            app.P6ValuePanel = uipanel(app.SetTFParameters);
            app.P6ValuePanel.Title = 'P6 Value';
            app.StartingSpinner_3Label_3 = uilabel(app.P6ValuePanel);
            app.StartingSpinner_3Label_3.Text = 'Starting ';
            app.StartingP6 = uispinner(app.P6ValuePanel);
            app.StartingP6.Value = 0.001;
            app.MinimumSpinner_3Label_3 = uilabel(app.P6ValuePanel);
            app.MinimumSpinner_3Label_3.Text = 'Minimum';
            app.MinimumP6 = uispinner(app.P6ValuePanel);
            app.MinimumP6.Value = 0.001;
            app.MaximumSpinner_3Label_3 = uilabel(app.P6ValuePanel);
            app.MaximumSpinner_3Label_3.Text = 'Maximum';
            app.MaximumP6 = uispinner(app.P6ValuePanel);
            app.equaImgButton = uibutton(app.SetTFParameters, 'push');
            app.equaImgButton.Icon = 'Equa_Gamma.jpg';
            app.equaImgButton.Text = '';
            app.equaLabel = uilabel(app.SetTFParameters);
            app.equaLabel.Text = '1-Gamma transfer function';
            app.SetDefaultButton = uibutton(app.SetTFParameters, 'push');
            app.SetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultButtonPushed, true);
            app.SetDefaultButton.Text = 'Set Default';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetParams(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetTFParameters)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetTFParameters)
        end
    end
end